classdef MPAPASS_Pref_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MPAPASS_Pref                 matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        GeneralTab                   matlab.ui.container.Tab
        DeveloperModeDropDownLabel   matlab.ui.control.Label
        DeveloperModeDropDown        matlab.ui.control.DropDown
        DatabaseExportDropDownLabel  matlab.ui.control.Label
        DatabaseExportDropDown       matlab.ui.control.DropDown
        FiguresTab                   matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % get database export preferences
            ind = contains(app.DatabaseExportDropDown.Items, getpref('MPAPASS','DatabaseExportFormat'));
            app.DatabaseExportDropDown.Value = app.DatabaseExportDropDown.Items{ind};
            % get developer access preferences
            ind = contains(app.DeveloperModeDropDown.Items, getpref('MPAPASS','DeveloperAcess'));
            app.DeveloperModeDropDown.Value = app.DeveloperModeDropDown.Items{ind};
        end

        % Value changed function: DeveloperModeDropDown
        function DeveloperModeDropDownValueChanged(app, event)
            switch app.DeveloperModeDropDown.Value
                case 'on'
                    selection = uiconfirm(app.MPAPASS_Pref,'By entering developer mode you understand that you will have access to features that are not fully tested or complete and may result in erroneous analysis or software crashes.','Confirm ',...
                        'Options',{'Continue','Cancel'},'Icon','warning');
                    switch selection
                        case 'Continue'
                            setpref('MPAPASS','DeveloperAcess','on')
                            
                        case 'Cancel'
                            app.DeveloperModeDropDown.Value = 'off';
                            setpref('MPAPASS','DeveloperAcess','off')
                            
                    end
                case 'off'
                    setpref('MPAPASS','DeveloperAcess','off')
                    
            end
            
        end

        % Value changed function: DatabaseExportDropDown
        function DatabaseExportDropDownValueChanged(app, event)
            
            setpref('MPAPASS','DatabaseExportFormat',app.DatabaseExportDropDown.Value)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MPAPASS_Pref = uifigure('Visible', 'off');
            app.MPAPASS_Pref.Name = 'Preferences';
            app.TabGroup = uitabgroup(app.MPAPASS_Pref);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.DeveloperModeDropDownLabel = uilabel(app.GeneralTab);
            app.DeveloperModeDropDownLabel.Text = 'Developer Mode';
            app.DeveloperModeDropDown = uidropdown(app.GeneralTab);
            app.DeveloperModeDropDown.Items = {'off', 'on'};
            app.DeveloperModeDropDown.ValueChangedFcn = createCallbackFcn(app, @DeveloperModeDropDownValueChanged, true);
            app.DeveloperModeDropDown.Value = 'off';
            app.DatabaseExportDropDownLabel = uilabel(app.GeneralTab);
            app.DatabaseExportDropDownLabel.Text = 'Database Export';
            app.DatabaseExportDropDown = uidropdown(app.GeneralTab);
            app.DatabaseExportDropDown.Items = {'.mat', '.csv'};
            app.DatabaseExportDropDown.ValueChangedFcn = createCallbackFcn(app, @DatabaseExportDropDownValueChanged, true);
            app.DatabaseExportDropDown.Value = '.mat';
            app.FiguresTab = uitab(app.TabGroup);
            app.FiguresTab.Title = 'Figures';
            % Show the figure after all components are created
            app.MPAPASS_Pref.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MPAPASS_Pref_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MPAPASS_Pref)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MPAPASS_Pref)
        end
    end
end