classdef MPA_Master_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MPAPASS                         matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        PreferencesMenu                 matlab.ui.container.Menu
        DatasetMenu                     matlab.ui.container.Menu
        CreateMenu                      matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        MergeMenu                       matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        NormalizedDatasetMenu           matlab.ui.container.Menu
        WindowsMenu                     matlab.ui.container.Menu
        CloseallwindowsMenu             matlab.ui.container.Menu
        ClearstatuswindowMenu           matlab.ui.container.Menu
        CloseoldfiguresMenu             matlab.ui.container.Menu
        NewAnalysisWindowMenu           matlab.ui.container.Menu
        TestMenu                        matlab.ui.container.Menu
        Lamp                            matlab.ui.control.Lamp
        Image                           matlab.ui.control.Image
        ThresholdEditField              matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        PlotRecoveryMapButton           matlab.ui.control.Button
        PlotBoxplotButton               matlab.ui.control.Button
        PlotIntensityMapButton          matlab.ui.control.Button
        NormalisationDropDown           matlab.ui.control.DropDown
        NormalisationDropDownLabel      matlab.ui.control.Label
        DatasetScalingDropDown          matlab.ui.control.DropDown
        DatasetScalingDropDownLabel     matlab.ui.control.Label
        NormaliseDatasetButton          matlab.ui.control.Button
        ThresholdDropDown               matlab.ui.control.DropDown
        ThresholdDropDownLabel          matlab.ui.control.Label
        ThresholdStatisticDropDown      matlab.ui.control.DropDown
        ThresholdStatisticDropDownLabel  matlab.ui.control.Label
        MainUITabs                      matlab.ui.container.TabGroup
        SelectionTab                    matlab.ui.container.Tab
        CustomVariablesListBox          matlab.ui.control.ListBox
        CustomVariablesListBox_2Label   matlab.ui.control.Label
        CustomHeadersListBox            matlab.ui.control.ListBox
        CustomHeadersListBoxLabel       matlab.ui.control.Label
        GroupsListBox                   matlab.ui.control.ListBox
        GroupsListBoxLabel              matlab.ui.control.Label
        DetectionAbListBox              matlab.ui.control.ListBox
        DetectionAbListBoxLabel         matlab.ui.control.Label
        SamplesListBox                  matlab.ui.control.ListBox
        SamplesListBoxLabel             matlab.ui.control.Label
        CaptureAbListBox                matlab.ui.control.ListBox
        CaptureAbListBoxLabel           matlab.ui.control.Label
        AbMixesListBox                  matlab.ui.control.ListBox
        AbMixesListBoxLabel             matlab.ui.control.Label
        DatabaseTab                     matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        SampleTab                       matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        LabellingTab                    matlab.ui.container.Tab
        UITable2                        matlab.ui.control.Table
        QualityControlTab               matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        BeadRecoveryTab                 matlab.ui.container.Tab
        BeadRecoveryThresholdEditField  matlab.ui.control.NumericEditField
        BeadRecoveryThresholdEditFieldLabel  matlab.ui.control.Label
        TotalBeadsBelowThreshold        matlab.ui.control.NumericEditField
        BeadsBelowThresholdListBox      matlab.ui.control.ListBox
        BeadsBelowThresholdListBoxLabel  matlab.ui.control.Label
        RecoveryHistogram               matlab.ui.control.UIAxes
        RawDataHistogramsTab            matlab.ui.container.Tab
        XAxisButtonGroup                matlab.ui.container.ButtonGroup
        LinearButton_2                  matlab.ui.control.ToggleButton
        Log10Button_2                   matlab.ui.control.ToggleButton
        PlotHistogramButton             matlab.ui.control.Button
        CaptureMarkerDropDown           matlab.ui.control.DropDown
        CaptureMarkerDropDownLabel      matlab.ui.control.Label
        DetectionMarkerDropDown         matlab.ui.control.DropDown
        DetectionMarkerDropDownLabel    matlab.ui.control.Label
        MixDropDown                     matlab.ui.control.DropDown
        MixDropDownLabel                matlab.ui.control.Label
        SampleSetIDDropDown             matlab.ui.control.DropDown
        SampleSetIDDropDownLabel        matlab.ui.control.Label
        RawDataHist                     matlab.ui.control.UIAxes
        PlateRecoveryTab                matlab.ui.container.Tab
        BeadDropDown                    matlab.ui.control.DropDown
        BeadDropDownLabel               matlab.ui.control.Label
        PlateNumberDropDown             matlab.ui.control.DropDown
        PlateNumberDropDownLabel        matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        IntensityplotsTab               matlab.ui.container.Tab
        TabGroup8                       matlab.ui.container.TabGroup
        HeatmapTab                      matlab.ui.container.Tab
        TabGroup6                       matlab.ui.container.TabGroup
        ClusteringTab                   matlab.ui.container.Tab
        ColorSamplesbyDropDown          matlab.ui.control.DropDown
        ColorSamplesbyDropDownLabel     matlab.ui.control.Label
        GroupMarkersbyDropDown          matlab.ui.control.DropDown
        GroupMarkersbyDropDownLabel     matlab.ui.control.Label
        MarkerOrderDropDown             matlab.ui.control.DropDown
        MarkerOrderDropDownLabel        matlab.ui.control.Label
        LinkageDropDown                 matlab.ui.control.DropDown
        LinkageDropDownLabel            matlab.ui.control.Label
        GroupSamplesbyDropDown          matlab.ui.control.DropDown
        GroupSamplesbyLabel             matlab.ui.control.Label
        SampleOrderDropDown             matlab.ui.control.DropDown
        SampleOrderDropDownLabel        matlab.ui.control.Label
        ClusteringDropDown              matlab.ui.control.DropDown
        ClusteringDropDownLabel         matlab.ui.control.Label
        OutputTab_3                     matlab.ui.container.Tab
        HeatmapFigureTitleEditField     matlab.ui.control.EditField
        FigureTitleEditFieldLabel       matlab.ui.control.Label
        ColorLevelsEditField            matlab.ui.control.NumericEditField
        ColorLevelsEditFieldLabel       matlab.ui.control.Label
        AspectRatioDropDown             matlab.ui.control.DropDown
        AspectRatioDropDownLabel        matlab.ui.control.Label
        ColorScaleDropDown              matlab.ui.control.DropDown
        ColorScaleDropDownLabel         matlab.ui.control.Label
        YLimEditFieldMax                matlab.ui.control.NumericEditField
        YLimEditFieldMin                matlab.ui.control.NumericEditField
        GridLinesDropDown               matlab.ui.control.DropDown
        GridLinesDropDownLabel          matlab.ui.control.Label
        HeatmapColorDropDown            matlab.ui.control.DropDown
        HeatmapColorDropDownLabel       matlab.ui.control.Label
        MarkerNamesDropDown             matlab.ui.control.DropDown
        MarkerNamesDropDownLabel        matlab.ui.control.Label
        HeatmapStyleDropDown            matlab.ui.control.DropDown
        HeatmapStyleDropDownLabel       matlab.ui.control.Label
        MarkerLabelsListBox             matlab.ui.control.ListBox
        MarkerLabelsListBoxLabel        matlab.ui.control.Label
        SampleLabelsListBox             matlab.ui.control.ListBox
        SampleLabelsListBoxLabel        matlab.ui.control.Label
        BoxplotTab                      matlab.ui.container.Tab
        BoxPlotFigureTitleEditField     matlab.ui.control.EditField
        FigureTitleEditField_2Label     matlab.ui.control.Label
        MarkerLabelsListBox_2           matlab.ui.control.ListBox
        MarkerLabelsListBox_2Label      matlab.ui.control.Label
        StyleDropDown                   matlab.ui.control.DropDown
        StyleDropDownLabel              matlab.ui.control.Label
        RankMethodDropDown              matlab.ui.control.DropDown
        RankMethodDropDownLabel         matlab.ui.control.Label
        RankBoxplotDropDown             matlab.ui.control.DropDown
        RankBoxplotDropDownLabel        matlab.ui.control.Label
        MarkerGroupNumberEditField      matlab.ui.control.NumericEditField
        MarkerGroupNumberEditFieldLabel  matlab.ui.control.Label
        GroupSamplesbyDropDown_2        matlab.ui.control.DropDown
        GroupSamplesbyDropDown_2Label   matlab.ui.control.Label
        MarkerDividersDropDown          matlab.ui.control.DropDown
        MarkerDividersDropDownLabel     matlab.ui.control.Label
        OrientationDropDown             matlab.ui.control.DropDown
        OrientationDropDownLabel        matlab.ui.control.Label
        GroupColourDropDown             matlab.ui.control.DropDown
        GroupColourDropDownLabel        matlab.ui.control.Label
        GroupSpacingSlider              matlab.ui.control.Slider
        GroupSpacingSliderLabel         matlab.ui.control.Label
        ClusteringTab_2                 matlab.ui.container.Tab
        TabGroup7                       matlab.ui.container.TabGroup
        PCATab_2                        matlab.ui.container.Tab
        PlotTypeDropDown                matlab.ui.control.DropDown
        PlotTypeDropDownLabel           matlab.ui.control.Label
        ColorbyDropDown_2               matlab.ui.control.DropDown
        ColorbyDropDown_2Label          matlab.ui.control.Label
        ExportPCADataSwitch             matlab.ui.control.Switch
        ExportPCADataSwitchLabel        matlab.ui.control.Label
        InteractivePlotButton_2         matlab.ui.control.Button
        RunPCAButton                    matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        tSNETab_2                       matlab.ui.container.Tab
        MaxIterEditField                matlab.ui.control.NumericEditField
        MaxIterEditFieldLabel           matlab.ui.control.Label
        ColorbyDropDown                 matlab.ui.control.DropDown
        ColorbyDropDownLabel            matlab.ui.control.Label
        DistanceMetricDropDown          matlab.ui.control.DropDown
        DistanceMetricDropDownLabel     matlab.ui.control.Label
        LearnRateEditField              matlab.ui.control.NumericEditField
        LearnRateEditFieldLabel         matlab.ui.control.Label
        PerplexityEditField             matlab.ui.control.NumericEditField
        PerplexityLabel                 matlab.ui.control.Label
        ExaggerationEditField           matlab.ui.control.NumericEditField
        ExaggerationEditFieldLabel      matlab.ui.control.Label
        AlgorithmButtonGroup            matlab.ui.container.ButtonGroup
        BarnesHutButton                 matlab.ui.control.ToggleButton
        ExactButton                     matlab.ui.control.ToggleButton
        InteractivePlotButton           matlab.ui.control.Button
        StoptSNEButton                  matlab.ui.control.Button
        RuntSNEButton                   matlab.ui.control.Button
        tSNEAnalysisButtonGroup         matlab.ui.container.ButtonGroup
        MarkersButton                   matlab.ui.control.ToggleButton
        SamplesButton                   matlab.ui.control.ToggleButton
        ClearPlotsButton                matlab.ui.control.Button
        DevergenceAxes                  matlab.ui.control.UIAxes
        EmbeddingAxes                   matlab.ui.control.UIAxes
        FigurePropertiesTab             matlab.ui.container.Tab
        FigureColorOrder7               matlab.ui.control.Button
        FigureColorOrder6               matlab.ui.control.Button
        FigureColorOrder5               matlab.ui.control.Button
        FigureColorOrder4               matlab.ui.control.Button
        FigureColorOrder3               matlab.ui.control.Button
        FigureColorOrder2               matlab.ui.control.Button
        FigureColorOrder1               matlab.ui.control.Button
        ColorOrderLabel                 matlab.ui.control.Label
        DefaultButton                   matlab.ui.control.Button
        ApplytofigureButton             matlab.ui.control.Button
        FigureOutputXDimEditField       matlab.ui.control.NumericEditField
        FigureOutputYDimEditField       matlab.ui.control.NumericEditField
        WidthHeightpixelsLabel          matlab.ui.control.Label
        OutputSizeDropDown              matlab.ui.control.DropDown
        OutputSizeDropDownLabel         matlab.ui.control.Label
        FigurePropertiesLabel           matlab.ui.control.Label
        FontStyleDropDown               matlab.ui.control.DropDown
        FontStyleDropDownLabel          matlab.ui.control.Label
        AxisFontEditField               matlab.ui.control.NumericEditField
        AxisFontEditFieldLabel          matlab.ui.control.Label
        AxisFontBold                    matlab.ui.control.Button
        LabelFontEditField              matlab.ui.control.NumericEditField
        LabelFontEditFieldLabel         matlab.ui.control.Label
        LabelFontBold                   matlab.ui.control.Button
        TitleFontEditField              matlab.ui.control.NumericEditField
        TitleFontEditFieldLabel         matlab.ui.control.Label
        FontPropertiesLabel             matlab.ui.control.Label
        TitleFontBold                   matlab.ui.control.Button
        LogTab                          matlab.ui.container.Tab
        CommandWindowListBox            matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        
        ImportApp = [];             % Import Application
        Dataset = [];               % Default Dataset
        Dataset_L = [];             % Recovery of Beads structure array (.EV, .Control)
        Norm_Dataset = [];          % Normalised Dataset
        Norm_Status = [1.00,0.00,0.00];
        MPA_Parameter = [];         % Analysis input parameter structure array
        Database = [];              % Sample information
        MPA_Beads = [];             % Multiplex bead information
        MixTable = [];              % Description
        SampleTableIndex = [];      % Description
        SelectedSampleTable = [];   % Description
        DatasetIndex = [];          % Description
        Labels = [];                % Description
        Column_No = [];             % Description
        
        GroupRef                    % Description
        Sample_Mix_Name             % Description
        CustomVariableIndex         % Description
        CustomVariablesItemIndex    % Description
        AbSelectionIndex            % Description
        AbSelectionItemIndex        % Description
        Selection                   % Description
        
        PCA_GColor                  % Description
        PCA_Plot                    % Description
        PCA_MarkerType              % Description
        PCA_GrpIndex                % Description
        PCA_Index_Label             % Description
        PCA_Index                   % Description
        PCA_Score                   % Description
        PCA_Coeff                   % Description
        PCA_Explained               % Description
        
        tSNE_GColor                 % Description
        tSNE_Plot                   % Description
        tSNE_MarkerType             % Description
        tSNE_KLog                   % Description
        tSNE_GrpIndex               % Description
        tSNE_Index                  % Description
        tSNE_Y                      % Description
        tSNE_Index_Label            % Description
        StopNow                     % Description
        
    end
    
    properties (Access = public)
        version % Description
        developerTabs
    end
    
    methods (Access = private)
        
        function Norm_Type(app, Norm_Button)
            
            switch Norm_Button
                case 'Background Subtraction'
                    app.MPA_Parameter.Norm = 1;
                    
                case 'Stain Index'
                    app.MPA_Parameter.Norm = 2;
                    
                case 'Fold Increase'
                    app.MPA_Parameter.Norm = 3;
            end
            
        end
        
        function SampleIndex(app)
            
            
            if isfield(app.Database, 'Norm') == 0
                UpdateStatusWindow(app, [' | Error: Normalise dataset to make selection'])
                
            else
                ClearVars(app)
                
                try
                    [SelectedData, SelectedColumnNames, SelectedRowNames,SelectedInfo, SelectedLabelInfo, Selection, status] = MPA_SampleIndex_v4(...
                        app.Database,...
                        app.CustomVariableIndex,...
                        app.CustomVariablesItemIndex,...
                        app.CustomHeadersListBox,...
                        app.SamplesListBox,...
                        app.GroupsListBox,...
                        app.AbMixesListBox,...
                        app.CaptureAbListBox,...
                        app.AbSelectionItemIndex, ...
                        app.MarkerLabelsListBox, ...
                        app.SampleLabelsListBox);
                    
                    if isempty(status)
                    else
                        for i = 1:numel(status)
                            UpdateStatusWindow(app, [' | ', status{i}]);
                        end
                        error
                    end
                    
                    
                    app.Selection.SelectedData          = SelectedData;
                    app.Selection.SelectedColumnNames   = SelectedColumnNames;
                    app.Selection.SelectedRowNames      = SelectedRowNames;
                    app.Selection.SelectedInfo          = SelectedInfo;
                    app.Selection.SelectedLabelInfo     = SelectedLabelInfo;
                    app.Selection.Selection             = Selection;
                    
                    
                    tSNEAnalysisButtonGroupSelectionChanged(app, @tSNEAnalysisButtonGroupSelectionChanged)
                    ColorbyDropDownValueChanged(app, @ColorbyDropDownUpdate)
                    ColorbyDropDown_2ValueChanged2(app, @ColorbyDropDown_2Update)
                    
                    return
                catch
                    uialert(app.MPAPASS,'Failed to index selection','Export error');
                    
                    UpdateStatusWindow(app, [' | Samples index 1 failed'])
                end
                
                try
                    app.UITable.Data = app.Selection.SelectedInfo;
                    app.UITable.ColumnName = app.Selection.SelectedInfo.Properties.VariableNames;
                catch
                    uialert(app.MPAPASS,'Failed to index selection in sample table','Export error');
                    UpdateStatusWindow(app, [' | Sample table index failed'])
                    
                end
                
                try
                    app.UITable2.Data = app.Selection.SelectedLabelInfo;
                    app.UITable2.ColumnName = app.Selection.SelectedLabelInfo.Properties.VariableNames;
                catch
                    uialert(app.MPAPASS,'Failed to index selection in marker table','Export error');
                    UpdateStatusWindow(app, [' | Marker table index failed'])
                    
                end
                
                %                 temp = num2cell(app.Selection.SelectedInfo.Sample_Set_ID);
                %                 app.xdataSampleIDDropDown.Items = cellfun(@num2str, temp);
                
            end
            
        end
        
        function SelectionDefault(app)
            
            UniqueMixNum = unique(app.Database.Labelling.Mix_Number,'stable');    % Unique Antibody Mixes
            
            % Load Default Listbox Values
            app.SamplesListBox.Items         = vertcat({'All'}, unique(string(app.Database.Sample.Sample_ID),'stable')); % Sample names
            app.SamplesListBox.ItemsData     = 1:numel(app.SamplesListBox.Items);
            
            app.GroupsListBox.Items          = vertcat({'All'}, unique(string(app.Database.Sample.Sample_Grouping_ID),'stable'));   % R Group
            app.GroupsListBox.ItemsData      = 1:numel(app.GroupsListBox.Items);
            
            app.CaptureAbListBox.Items       = vertcat({'All'}, app.Database.Beads.Bead_CaptureAntibody_Target(:));                                % Capture Bead Labels
            app.CaptureAbListBox.ItemsData   = 1:numel(app.CaptureAbListBox.Items);
            
            app.AbMixesListBox.Items          = vertcat({'All'}, unique(string(app.Database.Labelling.Mix_Number),'stable'));   % Unique mixes
            app.AbMixesListBox.ItemsData      = 1:numel(app.AbMixesListBox.Items);
            
            for i = 1:(numel(app.AbMixesListBox.Items)-1)
                app.AbSelectionItemIndex{i} = 1;
            end
            
            app.DetectionAbListBox.Items     = {'All'};  % Secondary antibodies
            app.DetectionAbListBox.ItemsData = 1;
            
            app.SampleSetIDDropDown.Items         = unique(string(app.Database.Sample.Sample_Set_ID),'stable');
            app.SampleSetIDDropDown.ItemsData     = 1:numel(app.SampleSetIDDropDown.Items);
            
            app.MixDropDown.Items            = unique(string(app.Database.Labelling.Mix_Number),'stable');
            app.MixDropDown.ItemsData        = 1:numel(app.MixDropDown.Items);
            
            app.DetectionMarkerDropDown.Items     = unique(string(app.Database.Labelling.Label_Target),'stable');
            app.DetectionMarkerDropDown.ItemsData = 1:numel(app.DetectionMarkerDropDown.Items);
            
            app.CaptureMarkerDropDown.Items     = app.Database.Beads.Bead_CaptureAntibody_Target;
            app.CaptureMarkerDropDown.ItemsData = 1:numel(app.CaptureMarkerDropDown.Items);
            
            app.DatasetIndex.Names              = (strcat(string(app.Database.Sample.Sample_ID), {' '}, string(app.Database.Sample.Sample_Label_Mix_No))); % sample + mix names
            app.DatasetIndex.Num                = 1:2:((2*numel(app.DatasetIndex.Names))-1); % index for samples in un-normalised dataset
            
            T_ind = 7;
            
            app.CustomHeadersListBox.Items      = vertcat({'Reset'}, strrep(app.Database.Sample.Properties.VariableNames(T_ind:end)','_',' '));
            app.CustomHeadersListBox.ItemsData  = 1:numel(app.CustomHeadersListBox.Items);
            
            vars = length(app.Database.Sample.Properties.VariableNames(T_ind:end)); % non integral vars
            diff = length(app.Database.Sample.Properties.VariableNames)-vars;
            for i = 1:vars
                app.CustomVariableIndex{i} = 1;
                app.CustomVariablesItemIndex{i} = vertcat({'All'}, string(table2cell(unique(app.Database.Sample(:,(i+diff))),'stable')));
            end
            
            app.UITable.Data = app.Database.Sample;
            app.UITable.ColumnName = app.Database.Sample.Properties.VariableNames;
            
            app.UITable2.Data = app.Database.Labelling;
            app.UITable2.ColumnName = app.Database.Labelling.Properties.VariableNames;
            
            app.GroupSamplesbyDropDown.Items = app.Database.Sample.Properties.VariableNames(:);
            app.GroupSamplesbyDropDown.ItemsData = 1:numel(app.GroupSamplesbyDropDown.Items);
            
            app.ColorSamplesbyDropDown.Items = vertcat('off',app.Database.Sample.Properties.VariableNames(:));
            app.ColorSamplesbyDropDown.ItemsData = 1:numel(app.ColorSamplesbyDropDown.Items);
            
            app.GroupSamplesbyDropDown_2.Items = app.Database.Sample.Properties.VariableNames(:);
            app.GroupSamplesbyDropDown_2.ItemsData = 1:numel(app.GroupSamplesbyDropDown_2.Items);
            app.GroupSamplesbyDropDown_2.Value = 4;
            
            app.GroupMarkersbyDropDown.Items = vertcat(app.Database.Labelling.Properties.VariableNames(:),app.Database.Beads.Properties.VariableNames(:));
            app.GroupMarkersbyDropDown.ItemsData = 1:numel(app.GroupMarkersbyDropDown.Items);
            
            app.DistanceMetricDropDown.ItemsData = 1:numel(app.DistanceMetricDropDown.Items);
            app.HeatmapColorDropDown.ItemsData = 1:numel(app.HeatmapColorDropDown.Items);
            
            app.ColorbyDropDown.Items = app.Database.Sample.Properties.VariableNames([2:5,7:end]);
            app.ColorbyDropDown.ItemsData = 1:numel(app.ColorbyDropDown.Items);
            
            app.ColorbyDropDown_2.Items = app.Database.Sample.Properties.VariableNames([2:5,7:end]);
            app.ColorbyDropDown_2.ItemsData = 1:numel(app.ColorbyDropDown_2.Items);
            
            app.SampleLabelsListBox.Items = app.Database.Sample.Properties.VariableNames([2:5,7:end]);
            app.SampleLabelsListBox.ItemsData = 1:numel(app.SampleLabelsListBox.Items);
            app.SampleLabelsListBox.Value = [1, 2];
            
            
            app.MarkerLabelsListBox.Items = vertcat(app.Database.Labelling.Properties.VariableNames(:),app.Database.Beads.Properties.VariableNames(:));
            app.MarkerLabelsListBox.ItemsData = 1:numel(app.MarkerLabelsListBox.Items);
            app.MarkerLabelsListBox.Value = [3, 10];
            
            
            app.MarkerLabelsListBox_2.Items = vertcat(app.Database.Labelling.Properties.VariableNames(:),app.Database.Beads.Properties.VariableNames(:));
            app.MarkerLabelsListBox_2.ItemsData = 1:numel(app.MarkerLabelsListBox.Items);
            app.MarkerLabelsListBox_2.Value = [3, 10];
            
            % Quality Control -> Plate Recovery Tab
            try
                app.PlateNumberDropDown.Items = unique(string(app.Database.Sample.Plate_Number),'stable');
                app.PlateNumberDropDown.ItemsData = 1:numel(app.PlateNumberDropDown.Items);
                
                app.BeadDropDown.Items = vertcat({'All'}, app.Database.Beads.Bead_CaptureAntibody_Target(:));                                % Capture Bead Labels
                app.BeadDropDown.ItemsData   = 1:numel(app.BeadDropDown.Items);
            catch
            end
        end


        function UpdateStatusWindow(app, string)
            i=length(app.CommandWindowListBox.Items)+1;
            app.CommandWindowListBox.Items(i)={[datestr(datetime('now')),string]};
            pause(0.1)
            app.CommandWindowListBox.scroll('bottom')
        end


        function    [S_Data, C_Data] = SampleIndexRawDataHistograms(app)
            
            ID = categorical(app.Database.Sample.Sample_Set_ID);
            ID2 = ID == app.SampleSetIDDropDown.Items{app.SampleSetIDDropDown.Value};
            
            Mix = categorical(app.Database.Sample.Sample_Label_Mix_No);
            Mix2 = Mix == app.MixDropDown.Items{app.MixDropDown.Value};
            S_ind = find(sum([ID2 Mix2],2)==2);
            
            Mixi = categorical(app.Database.Labelling.Mix_Number);
            Mixi2 = Mixi == app.MixDropDown.Items{app.MixDropDown.Value};
            
            app.DetectionMarkerDropDown.Items = app.Database.Labelling.Label_Target(Mixi2);
            app.DetectionMarkerDropDown.ItemsData = 1:numel(app.DetectionMarkerDropDown.Items);
            
            Ab = categorical(app.Database.Labelling.Label_Target);
            Ab2 = app.Database.Labelling.Import_Column_Number(Ab == app.DetectionMarkerDropDown.Items{app.DetectionMarkerDropDown.Value});
            
            Bead = categorical(app.Database.Beads.Bead_CaptureAntibody_Target);
            Bead2 = find(Bead == app.CaptureMarkerDropDown.Items{app.CaptureMarkerDropDown.Value});
            
            S_Data = app.Database.SampleDataset{Bead2, S_ind}(:,Ab2);
            C_ind = app.Database.Sample.Sample_Control_ID(S_ind);
            C_Data = app.Database.ControlDataset{Bead2, C_ind}(:,Ab2);
            
        end


        function FigureWindowControl(app)
            switch app.CloseoldfiguresMenu.Checked
                case 'on'
                    close all
                case 'off'
            end
            
        end
        
        function stop = KLLogging(app, optimValues, state, Group, col)
            persistent kllog iters stopnow
            switch state
                case 'init'
                    stopnow = false;
                    kllog = [];
                    iters = [];
                    
                case 'iter'
                    kllog = [kllog; optimValues.fval,log(norm(optimValues.grad))];
                    assignin('base','history',kllog)
                    iters = [iters; optimValues.iteration];
                    
                    if length(iters) > 1
                        
                        if app.tSNE_KLog > min(kllog(:,2))
                            app.tSNE_KLog = min(kllog(:,2));
                            
                            app.tSNE_Y = optimValues.Y;
                            
                            DrawtSNEPlot(app)
                            
                            yyaxis(app.DevergenceAxes, 'left')
                            plot(app.DevergenceAxes, iters, kllog(:,1), 'LineWidth', 2);
                            yyaxis(app.DevergenceAxes, 'right')
                            plot(app.DevergenceAxes, iters, kllog(:,2), 'LineWidth', 2);
                            
                            legend(app.DevergenceAxes, 'Divergence','log(norm(gradient))','Location','northoutside')
                            
                            drawnow
                        else
                            yyaxis(app.DevergenceAxes, 'left')
                            plot(app.DevergenceAxes, iters, kllog(:,1), 'LineWidth', 2);
                            yyaxis(app.DevergenceAxes, 'right')
                            plot(app.DevergenceAxes, iters, kllog(:,2), 'LineWidth', 2);
                            
                            legend(app.DevergenceAxes, 'Divergence','log(norm(gradient))','Location','northoutside')
                            
                            drawnow
                        end
                        
                    end
                    
                case 'done'
                    
            end
            
            stop = app.StopNow;
            
        end
        
        function ClearPlots(app)
            cla(app.EmbeddingAxes)
            cla(app.DevergenceAxes)
        end
        
        function [Dataset] = Dataset_scaling(app)
            
            [Dataset, Status] = MPA_Scaling_v2(app.Selection ,app.ThresholdDropDown, app.ThresholdStatisticDropDown, app.ThresholdEditField, app.DatasetScalingDropDown);    % apply thresholds to dataset
            
            for i = 1:numel(Status)
                UpdateStatusWindow(app, [' | ', Status{i}]);
            end
            
            
        end
        
        function SampleLabel = SampleLabelSelection(app, Dataset)
            
            SampleLabel = [];
            for i  = 1:numel(app.SampleLabelsListBox.Value)
                
                type = Dataset.SelectedInfo.(app.SampleLabelsListBox.Items{app.SampleLabelsListBox.Value(i)});
                type_class = class(type);
                
                switch type_class
                    case 'double'
                        
                        if i < numel(app.SampleLabelsListBox.Value)
                            SampleLabel =  strcat(SampleLabel, cellstr(num2str(type)), repmat({', '},size(Dataset.SelectedInfo,1),1));
                        else
                            SampleLabel =  strcat(SampleLabel, cellstr(num2str(type)));
                        end
                        
                    otherwise
                        if i < numel(app.SampleLabelsListBox.Value)
                            SampleLabel =  strcat(SampleLabel, type, repmat({', '},size(Dataset.SelectedInfo,1),1));
                        else
                            SampleLabel =  strcat(SampleLabel, type);
                        end
                end
            end
        end
        
        function SampleLabel = SampleLabelSelectionCorr(app, Dataset)
            
            SampleLabel = [];
            for i  = 1:numel(app.SampleLabelsListBox_2.Value)
                
                type = Dataset.SelectedInfo.(app.SampleLabelsListBox_2.Items{app.SampleLabelsListBox_2.Value(i)});
                type_class = class(type);
                
                switch type_class
                    case 'double'
                        
                        if i < numel(app.SampleLabelsListBox_2.Value)
                            SampleLabel =  strcat(SampleLabel, cellstr(num2str(type)), repmat({', '}, size(Dataset.SelectedInfo,1),1));
                        else
                            SampleLabel =  strcat(SampleLabel, cellstr(num2str(type)));
                        end
                        
                    otherwise
                        if i < numel(app.SampleLabelsListBox_2.Value)
                            SampleLabel =  strcat(SampleLabel, type, repmat({', '},size(Dataset.SelectedInfo,1),1));
                        else
                            SampleLabel =  strcat(SampleLabel, type);
                        end
                end
            end
        end
        
        function MarkerLabel = MarkerLabelSelection(app, Dataset)
            
            MarkerLabel = [];
            for i  = 1:numel(app.MarkerLabelsListBox_2.Value)
                var = Dataset.SelectedLabelInfo.(app.MarkerLabelsListBox_2.Items{app.MarkerLabelsListBox_2.Value(i)});
                
                if iscategorical(var)
                    var = cellstr(var);
                end
                
                if i < numel(app.MarkerLabelsListBox_2.Value)
                    MarkerLabel =  var;
                else
                    if i == 1
                        MarkerLabel =  (var);
                    else
                        MarkerLabel =  strcat(MarkerLabel,{', '}, var);
                    end
                    
                end
            end
            
            
        end
        
        function MarkerLabel = MarkerLabelSelectionCorr(app, Dataset)
            
            MarkerLabel = [];
            for i  = 1:numel(app.MarkerLabelsListBox_2.Value)
                var = Dataset.SelectedLabelInfo.(app.MarkerLabelsListBox_2.Items{app.MarkerLabelsListBox_2.Value(i)});
                
                if iscategorical(var)
                    var = cellstr(var);
                end
                
                if i < numel(app.MarkerLabelsListBox_2.Value)
                    MarkerLabel =  var;
                else
                    if i == 1
                        MarkerLabel =  (var);
                    else
                        MarkerLabel =  strcat(MarkerLabel,{', '}, var);
                    end
                    
                end
            end
            
            
        end
        
        function MarkerLabel = MarkerLabelSelectionBoxPlot(app, Dataset)
            
            MarkerLabel = [];
            for i  = 1:numel(app.MarkerLabelsListBox_2.Value)
                var = Dataset.SelectedLabelInfo.(app.MarkerLabelsListBox_2.Items{app.MarkerLabelsListBox_2.Value(i)});
                
                if iscategorical(var)
                    var = cellstr(var);
                end
                
                if i < numel(app.MarkerLabelsListBox_2.Value)
                    MarkerLabel =  var;
                else
                    if i == 1
                        MarkerLabel =  (var);
                    else
                        MarkerLabel =  strcat(MarkerLabel,{', '}, var);
                    end
                    
                end
            end
            
        end
        
        function PlateRecoveryDefault(app)
            for i = 1:12
                for ii = 1:8
                    pos = [i ii 0.75 0.75];
                    rectangle(app.UIAxes4,'Position',pos,'Curvature',[1 1], 'FaceColor', [1 1 1 ])
                end
            end
            app.UIAxes4.XLim = ([0.5 13.25]);
            app.UIAxes4.YLim = ([0.5 9.25]);
        end
        
        function PlateRecovery(app)
            
            cla(app.UIAxes4)
            
            WellIDs = categorical(app.Database.Sample.Plate_Well_ID);
            PlateIDs = app.Database.Sample.Plate_Number ==  app.PlateNumberDropDown.Value;
            SelectedIDs = WellIDs(PlateIDs);
            
            ind = false(numel(SelectedIDs(:)),1);
            
            PlotColInd = [1:12, 1:12, 1:12, 1:12, 1:12, 1:12, 1:12, 1:12];
            PlotRowInd = [1:8, 1:8, 1:8, 1:8, 1:8, 1:8, 1:8, 1:8, 1:8, 1:8, 1:8, 1:8];
            PlateCol = {'1','2','3','4','5','6','7','8','9','10','11','12'};
            PlateRow = {'A','B','C','D','E','F','G','H'};
            
            for i = 1:12
                for ii = 1:8
                    DataInd{ii,i} = [PlateRow{ii}, PlateCol{i}];
                    ind = or(ind, SelectedIDs == DataInd{ii,i});
                end
            end
            
            switch app.BeadDropDown.Items{app.BeadDropDown.Value}
                case 'All'
                    ScalingFactor = 64/max(max(sum(cell2mat(app.Database.SampleRecovery(:,ind)),1)));
                    MaxRecovery = max(max(sum(cell2mat(app.Database.SampleRecovery(:,ind)))));
                otherwise
                    ScalingFactor = 64/max(max(cell2mat(app.Database.SampleRecovery(app.BeadDropDown.Value-1,ind))));
                    MaxRecovery = max(max(cell2mat(app.Database.SampleRecovery(app.BeadDropDown.Value-1,ind))));
            end
            
            %% Plate Labels
            for i = 1:12
                columnnames = text(app.UIAxes4,i+(0.75/2),9,PlateCol{i},'HorizontalAlignment','center','FontWeight','bold','FontSize',14);
            end
            
            for i = 1:8
                rownames = text(app.UIAxes4,1-(0.6/2),i+(0.75/2),PlateRow{9-i},'HorizontalAlignment','center','FontWeight','bold','FontSize',14);
            end
            
            col = parula(64);
            
            for i = 1:12
                for ii = 1:8
                    
                    DataInd{ii,i} = [PlateRow{ii}, PlateCol{i}];
                    ind = SelectedIDs == DataInd{ii,i};
                    
                    switch app.BeadDropDown.Items{app.BeadDropDown.Value}
                        case 'All'
                            RecoveryData = round(sum(cell2mat(app.Database.SampleRecovery(:,ind)),1)*ScalingFactor,0);
                            if isempty(RecoveryData) == 1
                                pos = [i 9-ii 0.75 0.75];
                                rectangle(app.UIAxes4,'Position',pos,'Curvature',[1 1], 'FaceColor', [1 1 1 ])
                            else
                                pos = [i 9-ii 0.75 0.75];
                                rectangle(app.UIAxes4,'Position',pos,'Curvature',[1 1], 'FaceColor', col(RecoveryData,:))
                            end
                        otherwise
                            RecoveryData = round(sum(cell2mat(app.Database.SampleRecovery(app.BeadDropDown.Value-1,ind)),1)*ScalingFactor,0);
                            if isempty(RecoveryData) == 1
                                pos = [i 9-ii 0.75 0.75];
                                rectangle(app.UIAxes4,'Position',pos,'Curvature',[1 1], 'FaceColor', [1 1 1 ])
                            else
                                pos = [i 9-ii 0.75 0.75];
                                rectangle(app.UIAxes4,'Position',pos,'Curvature',[1 1], 'FaceColor', col(RecoveryData,:))
                            end
                    end
                    
                end
            end
            
            %% colorbar strings
            maxNo = round(MaxRecovery,0);
            colorbar(app.UIAxes4,'Ticks',[0 .25, 0.5, 0.75, 1],'TickLabels',{'0',num2str(round((maxNo/4),0)),num2str(round((maxNo/4)*2,0)),num2str(round((maxNo/4)*3,0)),num2str(maxNo)})
            
            app.UIAxes4.XLim = ([0.5 13.25]);
            app.UIAxes4.YLim = ([0.5 9.25]);
        end
        
        function results = DrawtSNEPlot(app)
            LoopIndex = numel(unique(app.tSNE_GrpIndex));
            for i = 1:LoopIndex
                app.tSNE_Plot = scatter(app.EmbeddingAxes, app.tSNE_Y(app.tSNE_GrpIndex==i,1), app.tSNE_Y(app.tSNE_GrpIndex==i,2), 100, app.tSNE_GColor(app.tSNE_GrpIndex==i,:), 'filled', 'Marker',app.tSNE_MarkerType{i}, 'MarkerFaceAlpha',.7,'MarkerEdgeColor','k');
                hold(app.EmbeddingAxes,'on')
            end
            hold(app.EmbeddingAxes,'off')
            
            
        end
        
        function results = Draw_PCA_2DPlot(app)
            
            cla(app.UIAxes3)
            legend(app.UIAxes3,'off')
            LoopIndex = numel(unique(app.PCA_GrpIndex));
            
            for i = 1:LoopIndex
                app.PCA_Plot = scatter(app.UIAxes3, app.PCA_Score(app.PCA_GrpIndex==i,1), app.PCA_Score(app.PCA_GrpIndex==i,2), 100, app.PCA_GColor(app.PCA_GrpIndex==i,:), 'filled', 'Marker',app.PCA_MarkerType{i}, 'MarkerFaceAlpha',.7,'MarkerEdgeColor','k');
                hold(app.UIAxes3,'on')
                
                switch class(app.PCA_Index_Label)
                    case 'double'
                        label = cellstr(num2str(app.PCA_Index_Label));
                        Names{i} = label{i};
                    otherwise
                        Names{i} = app.PCA_Index_Label{i};
                end
            end
            hold(app.UIAxes3,'off')
            
            app.UIAxes3.Box = 'on';
            app.UIAxes3.XLabel.String = ['Component 1 (', num2str(round(app.PCA_Explained(1),1)),'%)'];
            app.UIAxes3.YLabel.String = ['Component 2 (', num2str(round(app.PCA_Explained(2),1)),'%)'];
            app.UIAxes3.FontSize = 14;
            
            
            if numel(Names) > 50
                ColNum = 3;
                UpdateStatusWindow(app, [' | Too many groups (>50) to display legend'])
            elseif numel(Names) > 48
                ColNum = 3;
            elseif numel(Names) > 24
                ColNum = 2;
            else
                ColNum = 1;
            end
            
            legend(app.UIAxes3, Names,'Location',"eastoutside",'FontSize',14,'Box','off','NumColumns',ColNum,'Color',[0.96 0.96 0.96]);
            
        end
        
        function Export_PCA_2DPlot(app)
            figure;
            LoopIndex = numel(unique(app.PCA_GrpIndex));
            PCAax = axes;
            for i = 1:LoopIndex
                app.PCA_Plot = scatter(PCAax, app.PCA_Score(app.PCA_GrpIndex==i,1), app.PCA_Score(app.PCA_GrpIndex==i,2), 100, app.PCA_GColor(app.PCA_GrpIndex==i,:), 'filled', 'Marker',app.PCA_MarkerType{i}, 'MarkerFaceAlpha',.7,'MarkerEdgeColor','k');
                hold(PCAax,'on')
                switch class(app.PCA_Index_Label)
                    case 'double'
                        label = cellstr(num2str(app.PCA_Index_Label));
                        Names{i} = label{i};
                    otherwise
                        Names{i} = app.PCA_Index_Label{i};
                end
            end
            hold(PCAax,'off')
            
            PCAax.Box = 'on';
            ylabel(['Component 1 (', num2str(round(app.PCA_Explained(1),1)),'%)'],'FontSize', 14, 'FontWeight','bold');
            xlabel(['Component 2 (', num2str(round(app.PCA_Explained(2),1)),'%)'],'FontSize', 14, 'FontWeight','bold');
            
            
            if numel(Names) > 50
                NumCol = 3;
                UpdateStatusWindow(app, [' | Too many groups (>50) to display legend'])
            elseif numel(Names) > 48
                NumCol = 3;
            elseif numel(Names) > 24
                NumCol = 2;
            else
                NumCol = 1;
            end
            
            PCAax.FontSize = 13;
            
            [~,hdl]=legend(PCAax, Names,'Location',"eastoutside",'FontSize',14,'Box','off','Color',[0.96 0.96 0.96],'NumColumns',NumCol);
            objhl = findobj(hdl, 'type', 'patch'); % objects of legend of type patch
            set(objhl, 'Markersize', 10); %
            
        end
        function  Draw_PCA_3DPlot(app)
            
            cla(app.UIAxes3)
            legend(app.UIAxes3,'off')
            LoopIndex = numel(unique(app.PCA_GrpIndex));
            for i = 1:LoopIndex
                app.PCA_Plot = scatter3(app.UIAxes3, app.PCA_Score(app.PCA_GrpIndex==i,1), app.PCA_Score(app.PCA_GrpIndex==i,2),app.PCA_Score(app.PCA_GrpIndex==i,3), 100, app.PCA_GColor(app.PCA_GrpIndex==i,:), 'filled', 'Marker',app.PCA_MarkerType{i}, 'MarkerFaceAlpha',.7,'MarkerEdgeColor','k');
                hold(app.UIAxes3,'on')
                
                switch class(app.PCA_Index_Label)
                    case 'double'
                        label = cellstr(num2str(app.PCA_Index_Label));
                        Names{i} = label{i};
                    otherwise
                        Names{i} = app.PCA_Index_Label{i};
                end
            end
            hold(app.UIAxes3,'off')
            
            app.UIAxes3.Box = 'on';
            app.UIAxes3.XLabel.String = ['Component 1 (', num2str(round(app.PCA_Explained(1),1)),'%)'];
            app.UIAxes3.YLabel.String = ['Component 2 (', num2str(round(app.PCA_Explained(2),1)),'%)'];
            app.UIAxes3.ZLabel.String = ['Component 3 (', num2str(round(app.PCA_Explained(3),3)),'%)'];
            app.UIAxes3.FontSize = 14;
            
            if numel(Names) > 50
                ColNum = 3;
                UpdateStatusWindow(app, [' | Too many groups (>50) to display legend'])
            elseif numel(Names) > 48
                ColNum = 3;
            elseif numel(Names) > 24
                ColNum = 2;
            else
                ColNum = 1;
            end
            
            legend(app.UIAxes3, Names,'Location',"eastoutside",'FontSize',14,'Box','off','NumColumns',ColNum,'Color',[0.96 0.96 0.96]);
            
        end
        
        function  ClearVars(app)
            app.PCA_GColor   = [];
            app.PCA_Plot   = [];
            app.PCA_MarkerType    = [];
            app.PCA_GrpIndex     = [];
            app.PCA_Index_Label   = [];
            app.PCA_Index        = [];
            app.PCA_Score       = [];
            app.PCA_Coeff      = [];
            app.PCA_Explained    = [];
            
            app.tSNE_GColor     = [];
            app.tSNE_Plot     = [];
            app.tSNE_MarkerType    = [];
            app.tSNE_KLog        = [];
            app.tSNE_GrpIndex      = [];
            app.tSNE_Index        = [];
            app.tSNE_Y            = [];
            app.tSNE_Index_Label   = [];
            app.StopNow          = [];
            
        end
        
        function results = IndexFigureProperties(app)
            app.FontStyleDropDown.Items = listfonts;
            app.FontStyleDropDown.ItemsData = 1:numel(app.FontStyleDropDown.Items);
            app.FontStyleDropDown.Value = find(strcmp(app.FontStyleDropDown.Items, getpref('MPAPASS','GlobalFigure_LabelFontStyle')));
            
            %% title font properties
            app.TitleFontEditField.Value = getpref('MPAPASS','GlobalFigure_TitleFontSize');
            
            switch getpref('MPAPASS','GlobalFigure_TitleFontBold')
                case 'on'
                    app.TitleFontBold.BackgroundColor = [0.80,0.80,0.80];
                case 'off'
                    app.TitleFontBold.BackgroundColor = [0.96,0.96,0.96];
            end
            
            
            %% label font properties
            app.LabelFontEditField.Value = getpref('MPAPASS','GlobalFigure_LabelFontSize');
            
            switch getpref('MPAPASS','GlobalFigure_LabelFontBold')
                case 'on'
                    app.LabelFontBold.BackgroundColor = [0.80,0.80,0.80];
                case 'off'
                    app.LabelFontBold.BackgroundColor = [0.96,0.96,0.96];
            end
            
            
            %% axis font properties
            app.AxisFontEditField.Value = getpref('MPAPASS','GlobalFigure_AxesFontSize');
            
            switch getpref('MPAPASS','GlobalFigure_AxesFontBold')
                case 'on'
                    app.AxisFontBold.BackgroundColor = [0.80,0.80,0.80];
                case 'off'
                    app.AxisFontBold.BackgroundColor = [0.96,0.96,0.96];
            end
            
            
            % figure properties
            app.OutputSizeDropDown.Value = getpref('MPAPASS','GlobalFigure_FigureOutputSize');
            Dims = getpref('MPAPASS','GlobalFigure_FigureOutputDim');
            app.FigureOutputXDimEditField.Value = Dims(1);
            app.FigureOutputYDimEditField.Value = Dims(2);
            
            % figure color order
            colors = getpref('MPAPASS','GlobalFigure_FigureColorOrder');
            app.FigureColorOrder1.BackgroundColor = colors{1}(1,:);
            app.FigureColorOrder2.BackgroundColor = colors{1}(2,:);
            app.FigureColorOrder3.BackgroundColor = colors{1}(3,:);
            app.FigureColorOrder4.BackgroundColor = colors{1}(4,:);
            app.FigureColorOrder5.BackgroundColor = colors{1}(5,:);
            app.FigureColorOrder6.BackgroundColor = colors{1}(6,:);
            app.FigureColorOrder7.BackgroundColor = colors{1}(7,:);
            
        end
        
        
    end
    
    methods (Access = public)
        
        function SendDataToMainApp(app, Database)    % get data from second app
            
            try
                app.Database = Database;
            catch
                UpdateStatusWindow(app, [' | Error loading dataset '])
                return
            end
            
            UpdateStatusWindow(app, [' | Dataset loaded '])
            UpdateStatusWindow(app, [' | Import window closed'])
            SelectionDefault(app)
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Dataset)
            app.version = 1.01;
            [app]=MPAPASS_SoftwareUpdates(app);
            
            [app] = MPAPASS_Preference_Check(app); % perform preference check/update
            [app] = MPAPASS_Developer_Access(app); % turn off developer features by default
            IndexFigureProperties(app)
            
            PlateRecoveryDefault(app)
        end

        % Menu selected function: LoadMenu
        function LoadDatasetMenuSelected(app, event)
            
            app.Database = [];
            app.MPA_Beads = [];
            app.Norm_Status = [1, 0,0];
            app.Lamp.Color = app.Norm_Status;
            ClearVars(app);
            
            try
                app.MPAPASS.Visible = 'off';
                [filename, foldername] = uigetfile('*.mat', 'Select a mat file');
                app.MPAPASS.Visible = 'on';
                if ~isempty(filename)
                    fullname = fullfile(foldername, filename);
                    
                    Temp = load(fullname);
                    
                    app.Database = Temp.Database;          % import dataset sample information
                    app.MPA_Beads = Temp.Database.Beads;   % import bead information
                else
                    return
                end
                
            catch
                uialert(app.MPAPASS,'Failed to load dataset','Dataset Load Error');
                UpdateStatusWindow(app, [' | Error loading dataset'])
                
                return
                
            end
            
            UpdateStatusWindow(app, [' | Dataset loaded '])
            
            try
                SelectionDefault(app)
            catch
                uialert(app.MPAPASS,'Failed to initiate default settings','Settings Error');
                UpdateStatusWindow(app, [' | Default Settings Failed'])
                
                return
                
            end
            try
                UpdateRecoveryPlotButtonPushed(app)
            catch
                
                UpdateStatusWindow(app, [' | Recovery plot failed'])
                uialert(app.MPAPASS,'Recovery bead plot failed','Plotting Error');
                return
                
            end
            try
                PlateRecovery(app)
            catch
                
                UpdateStatusWindow(app, [' | Plate recovery failed'])
                return
                
            end
            
        end

        % Menu selected function: CreateMenu
        function CreateDatasetMenuSelected(app, event)
            
            app.ImportApp=Import_GUI(app);
            
            UpdateStatusWindow(app, [' | Import window opened'])
            
        end

        % Button pushed function: NormaliseDatasetButton
        function NormaliseDatasetButtonPushed(app, event)
            
            d = uiprogressdlg(app.MPAPASS,'Title','Please Wait',...
                'Message','Normalizing dataset','Indeterminate','on');
            
            if isfield(app.Database, 'SampleDataset')==0
                app.Norm_Status = [1.00,0.00,0.00];
                
                UpdateStatusWindow(app, [' | Error: no dataset loaded'])
                uialert(app.MPAPASS,'Dataset not loaded','Normalization Error');
                return
                
            elseif isfield(app.Database, 'ControlDataset')==0
                app.Norm_Status = [1.00,0.00,0.00];
                
                UpdateStatusWindow(app, [' | Error: no dataset loaded'])
                uialert(app.MPAPASS,'Dataset not loaded','Normalization Error');
                return
                
            else
                
                UpdateStatusWindow(app, [' | Dataset normalisation initiated...'])
                pause(0.5)
            end
            
            try
                ClearVars(app)
                Norm_Type(app, app.NormalisationDropDown.Value) ;
                
                [app.Database] = MPA_EV_Bead_v_Control_v3(app.Database, app.NormalisationDropDown.Value, app.DatasetScalingDropDown.Value);
                
                app.Norm_Status = [0.00,1.00,0.00];
                UpdateStatusWindow(app, [' | Dataset normalised using ', app.NormalisationDropDown.Value])
            catch
                app.Norm_Status = [1.00,0.00,0.00];
                UpdateStatusWindow(app, [' | Dataset normalisation failed'])
                uialert(app.MPAPASS,'Dataset normalization failed','Normalization Error');
            end
            
            try
                SampleIndex(app)
                app.Norm_Status = [0.00,1.00,0.00];
                UpdateStatusWindow(app, [' | Samples indexed'])
            catch
                app.Norm_Status = [1.00,0.00,0.00];
                UpdateStatusWindow(app, [' | Samples index failed'])
                uialert(app.MPAPASS,'Samples selection failed to index','Normalization Error');
                
            end
            
            try
                UpdateRecoveryPlotButtonPushed(app, event)
                app.Norm_Status = [0.00,1.00,0.00];
                UpdateStatusWindow(app, [' | Bead recovery plotted'])
                
            catch
                app.Norm_Status = [1.00,0.00,0.00];
                UpdateStatusWindow(app, [' | Error: Unable to plot bead recovery'])
                uialert(app.MPAPASS,'Unable to plot bead recovery','Plotting error');
            end
            
            app.Lamp.Color = app.Norm_Status;
            close all
            close(d)
            
        end

        % Callback function
        function CloseAllFigWindowsButtonPushed(app, event)
            close all
        end

        % Menu selected function: NormalizedDatasetMenu
        function ExportNormDatasetMenuSelected(app, event)
            
            %             try
            [Dataset] = Dataset_scaling(app);
            
            SampleIndex(app)
            
            PlotData = app.Selection.SelectedData;
            
            SampleLabel = app.Selection.SelectedColumnNames;
            
            MarkerLabel = app.Selection.SelectedRowNames;
            
            
            switch getpref('MPAPASS','DatabaseExportFormat')
                case '.mat'
                    uisave({'SampleLabel','MarkerLabel','PlotData'},'DatabaseExport')
                case '.csv'
                    Data = cell(size(PlotData,1)+1,size(PlotData,2)+1);
                    Data(2:end,1) = MarkerLabel;
                    Data(1,2:end) = SampleLabel;
                    Data(2:end,2:end) = num2cell(PlotData);
                    [file, path] = uiputfile('normalized dataset.csv');
                    if  file ~= 0
                        writecell(Data,fullfile(path,file))
                    end
            end
            UpdateStatusWindow(app, [' | Error: File saved'])
            
            %             catch
            %                 uialert(app.MPAPASS,'Failed to export normalized dataset','Export error');
            %                 UpdateStatusWindow(app, [' | File save cancelled'])
            %                 return
            %
            %             end
            
        end

        % Value changed function: SamplesListBox
        function SamplesListBoxValueChanged(app, event)
            
            SampleIndex(app)
            ClearPlots(app)
            
        end

        % Value changed function: GroupsListBox
        function GroupsListBoxValueChanged(app, event)
            
            SampleIndex(app)
            ClearPlots(app)
            
        end

        % Value changed function: AbMixesListBox
        function AbMixesListBoxValueChanged(app, event)
            
            
            for i = 1:numel(app.AbMixesListBox.Value)
                if app.AbMixesListBox.Value(i) == 1
                    app.DetectionAbListBox.Items = {'All'};
                    app.DetectionAbListBox.ItemsData = 1;
                else
                    AbIndex = app.Database.Labelling.Label_Target(app.Database.Labelling.Mix_Number == (app.AbMixesListBox.Value(i)-1));
                    app.DetectionAbListBox.Items = vertcat({'All'}, string(unique(AbIndex,'stable')));
                    app.DetectionAbListBox.ItemsData = 1:numel(app.DetectionAbListBox.Items);
                    app.DetectionAbListBox.Value = app.AbSelectionItemIndex{app.AbMixesListBox.Value(i)-1};
                end
            end
            
            SampleIndex(app)
            ClearPlots(app)
            
        end

        % Value changed function: DetectionAbListBox
        function DetectionAbListBoxValueChanged(app, event)
            
            if app.AbMixesListBox.Value == 1
            else
                app.AbSelectionItemIndex{app.AbMixesListBox.Value-1} = app.DetectionAbListBox.Value;
            end
            
            SampleIndex(app)
            ClearPlots(app)
            
        end

        % Value changed function: CaptureAbListBox
        function CaptureAbListBoxValueChanged(app, event)
            
            SampleIndex(app)
            ClearPlots(app)
            
        end

        % Value changed function: BeadRecoveryThresholdEditField
        function UpdateRecoveryPlotButtonPushed(app, event)
            try
                app.BeadsBelowThresholdListBox.Items = {};
                
                Recovery_Data = [cell2mat(app.Database.SampleRecovery),cell2mat(app.Database.ControlRecovery)] ;
                
                bins=round((max(Recovery_Data(:))-min(Recovery_Data(:)))/10);
                h=histogram(app.RecoveryHistogram,Recovery_Data(:),bins);
                h(1).EdgeAlpha=1;
                xlabel(app.RecoveryHistogram,'Bead Population Recovery')
                ylabel(app.RecoveryHistogram,'Frequency')
                app.RecoveryHistogram.XLim= ([0 inf]);
                app.RecoveryHistogram.XTick = 0:100:10000;
                ylim = app.RecoveryHistogram.YLim;
                hold(app.RecoveryHistogram,'on')
                plot(app.RecoveryHistogram, [app.BeadRecoveryThresholdEditField.Value app.BeadRecoveryThresholdEditField.Value],[ylim],'Color','r','LineStyle',':','LineWidth',2)
                hold(app.RecoveryHistogram,'off')
                legend(app.RecoveryHistogram.Children(1),'Bead Recovery Threshold','Location','NorthEast','edgecolor','none')
                EV_Recovery_Array = cell2mat(app.Database.SampleRecovery);
                EV_Recovery_Array = EV_Recovery_Array(:);
                
                [Sample_Mix_Bead_Array, app.Sample_Mix_Name]=MPA_BeadRecoveryList(app.Database);
                str = strcat(num2str(EV_Recovery_Array(EV_Recovery_Array<app.BeadRecoveryThresholdEditField.Value)), {' Beads | '}, Sample_Mix_Bead_Array(EV_Recovery_Array<app.BeadRecoveryThresholdEditField.Value));
                
                app.BeadsBelowThresholdListBox.Items  = string(str);
                app.TotalBeadsBelowThreshold.Value = numel(app.BeadsBelowThresholdListBox.Items);
            catch
            end
            
            PlotHistogramButtonPushed(app)
        end

        % Callback function: CaptureMarkerDropDown, 
        % DetectionMarkerDropDown, PlotHistogramButton
        function PlotHistogramButtonPushed(app, event)
            reset(app.RawDataHist)
            app.RawDataHist.BackgroundColor = [1.00,1.00,1.00];
            app.RawDataHist.FontSize = 15;
            
            [S_Data, C_Data] = SampleIndexRawDataHistograms(app);
            
            switch app.XAxisButtonGroup.SelectedObject.Text
                case 'Log10'
                    
                    S_Data(S_Data<=0) = [];
                    C_Data(C_Data<=0) = [];
                    
                    min_bin = round(log10(min([S_Data(:); C_Data(:)]))-1,0);
                    max_bin = round(log10(max([S_Data(:); C_Data(:)]))+1,0);
                    
                    edges = logspace(min_bin,max_bin, 128);
                    max(S_Data)
                    
                case 'Linear'
                    
                    min_bin(1)=min(C_Data);
                    min_bin(2)=min(S_Data);
                    min_edge= round(min(min_bin)-(min(min_bin)*0.1));
                    
                    max_bin(1) = max(C_Data);
                    max_bin(2) = max(S_Data);
                    max_edge = round(max(max_bin)*1.1);
                    
                    edges = min_edge:5:max_edge;
                    
            end
            
            
            h(1)=histogram(app.RawDataHist, C_Data,edges,...
                'normalization', 'count',...
                'DisplayStyle', "bar",...
                'LineWidth', 2,...
                'EdgeAlpha', 0.2);
            
            hold(app.RawDataHist, 'on');
            h(2)=histogram(app.RawDataHist, S_Data,edges,...
                'normalization', 'count',...
                'DisplayStyle', "bar",...
                'LineWidth', 2,...
                'EdgeAlpha', 0.2);
            
            legend(app.RawDataHist,'Control', 'Sample','FontSize',14)
            hold(app.RawDataHist, 'off');
            
            % plot appearence
            
            app.RawDataHist.Box = 'on';
            
            switch app.XAxisButtonGroup.SelectedObject.Text
                case 'Log10'
                    str = string(['Log_{10} ', char(app.CaptureMarkerDropDown.Items(app.CaptureMarkerDropDown.Value)), ' Bead + ', char(app.DetectionMarkerDropDown.Items(app.DetectionMarkerDropDown.Value)), ' Intensity']);
                case 'Linear'
                    str = string([char(app.CaptureMarkerDropDown.Items(app.CaptureMarkerDropDown.Value)), ' Bead + ', char(app.DetectionMarkerDropDown.Items(app.DetectionMarkerDropDown.Value)), ' Intensity']);
            end
            xlabel(app.RawDataHist, str,'FontWeight','bold');
            ylabel(app.RawDataHist, 'Count','FontWeight','bold');
            
            
            switch app.XAxisButtonGroup.SelectedObject.Text
                case 'Log10'
                    app.RawDataHist.XScale = 'log';
                    %                     app.RawDataHist.XLim = [1 1e7];
                case 'Linear'
                    app.RawDataHist.XScale = 'linear';
                    %                     app.RawDataHist.XLim = [-inf inf];
            end
            
            
        end

        % Menu selected function: ClearstatuswindowMenu
        function ClearStatusWindowMenuSelected(app, event)
            app.CommandWindowListBox.Items = {};
        end

        % Menu selected function: CloseallwindowsMenu
        function CloseallwindowsMenuSelected(app, event)
            close all
            
        end

        % Button pushed function: PlotIntensityMapButton
        function PlotIntensityMapButtonPushed(app, event)
            wait = uiprogressdlg(app.MPAPASS,'Title','Please Wait',...
                'Message','Plotting intensity heatmap','Indeterminate','on');
            try
                FigureWindowControl(app)
                
                if isfield(app.Selection, 'SelectedData') == 1
                else
                    uialert(app.MPAPASS,'Normalize Dataset','Plotting error');
                    UpdateStatusWindow(app, [' | Error: Normalise data'])
                    return
                end
                
                SampleIndex(app)
                
                PlotData = app.Selection.SelectedData;
                
                SampleLabel = app.Selection.SelectedColumnNames;
                
                MarkerLabel = app.Selection.SelectedRowNames;
                
                PlotData(isnan(PlotData)) = 0;
                PlotData(isinf(PlotData)) = 0;
                
                [PlotData, MarkerLabel, SampleLabel, RowLeafOrder, ColumnLeafOrder, Z1, Z2] = MPA_Database_Clustering_Grouping_v1(app.Selection, PlotData, MarkerLabel, SampleLabel, app.MarkerOrderDropDown, app.SampleOrderDropDown, app.ClusteringDropDown, app.LinkageDropDown, app.GroupMarkersbyDropDown, app.GroupSamplesbyDropDown);
                
                switch app.ColorSamplesbyDropDown.Items{app.ColorSamplesbyDropDown.Value}
                    case 'off'
                    otherwise
                        SampleLabel = [];
                end
                
                switch app.MarkerNamesDropDown.Value
                    case 'on'
                    case 'off'
                        MarkerLabel = [];
                end
                
                fig = figure('Visible','on','units','normalized','outerposition',[0 0 1 1]); % create figure handle
                switch app.ClusteringDropDown.Value %% rectangle plot dendrograms
                    
                    case 'No Clustering'
                        HM = nexttile;
                    otherwise
                        HM = axes('Parent',fig);   % create axes handle
                end
                
                switch app.HeatmapStyleDropDown.Value
                    case 'Classic' % plot heatmap
                        
                        MPA_Heatmap_v1(PlotData', MarkerLabel, SampleLabel, app.GridLinesDropDown.Value)
                        
                        set(gca,'TickLabelInterpreter', 'tex', 'TickLength', [0 0], 'Tag', 'heatmap');
                        HM.ActivePositionProperty = 'position';
                        
                        pos = HM.Position;
                        switch app.AspectRatioDropDown.Value
                            case 'on'
                                % apply correct aspect ratio when plotting
                                if numel(MarkerLabel)>numel(SampleLabel)
                                    bottom = (1-pos(3)/(numel(MarkerLabel)/numel(SampleLabel)))/2;
                                    
                                    HM.Position = [pos(1) bottom pos(3) pos(3)/(numel(MarkerLabel)/numel(SampleLabel))];
                                else
                                    left = (1-pos(4)/(numel(SampleLabel)/numel(MarkerLabel)))/2;
                                    HM.Position = [left pos(2) pos(4)/(numel(SampleLabel)/numel(MarkerLabel)) pos(4)];
                                end
                            case 'off'
                        end
                        
                        pos = HM.Position;
                        UpdateStatusWindow(app, [' | Bead Intensity Heatmap Plotted'])
                        
                        switch app.ClusteringDropDown.Value %% rectangle plot dendrograms
                            
                            case 'No Clustering'
                                
                            otherwise
                                
                                if isempty(ColumnLeafOrder) == 1
                                else
                                    ax1 = axes;
                                    d = dendrogram(Z2,0,'Reorder',fliplr(ColumnLeafOrder), 'Orientation','right');
                                    set(d,'LineWidth',0.75,'Color','k','LineStyle','-')
                                    ax1.Position= [pos(1)+pos(3), pos(2) 0.055 pos(4)];
                                    ax1.ActivePositionProperty = 'position';
                                    ax1.Color = 'none';
                                    set(ax1,'visible','off')
                                    ax1.YLim = [0.5 numel(ColumnLeafOrder)+0.5];
                                    ax1.Tag = 'Sample Dendrogram';
                                    hold on
                                end
                                
                                if isempty(RowLeafOrder) == 1
                                else
                                    ax2 = axes;
                                    d2 = dendrogram(Z1,0,'Reorder',RowLeafOrder, 'Orientation','top');
                                    set(d2,'LineWidth',0.75,'Color','k','LineStyle','-')
                                    ax2.Position= [pos(1), pos(2)+pos(4) pos(3) 0.06];
                                    ax2.ActivePositionProperty = 'position';
                                    ax2.Color = 'none';
                                    set(ax2,'visible','off')
                                    ax2.Tag = 'Marker Dendrogram';
                                    ax2.XLim = [0.5 (numel(RowLeafOrder)+0.5)];
                                    hold on
                                end
                        end


                    case 'Circular'
                        
                        Dat = {PlotData};
                        
                        [fig]= MPA_CirHeatmap_v1(fig, Dat,...
                            'CircType','tq',...
                            'OuterLabels', MarkerLabel,...
                            'InnerSpacerSize', 1,...
                            'ShowYLabels',app.MarkerNamesDropDown.Value,...
                            'GroupLabels', SampleLabel);
                        
                        
                        HM.Tag = 'heatmap';
                        
                        switch app.ClusteringDropDown.Value % circle plot dendrograms
                            case 'No Clustering'
                            otherwise
                                if isempty(ColumnLeafOrder) == 1
                                else
                                end
                                if isempty(RowLeafOrder) == 1
                                else
                                    hold on
                                    ax = axes;
                                    MPA_polardendrogram_v1(Z1,0,'Reorder',RowLeafOrder);
                                    camroll(90)
                                end
                        end
                        
                end
                
                Data = cell2mat(app.Database.Norm.Cell);
                
                Data(isnan(Data)) = 0;
                Data(isinf(Data)) = 0;
                
                MaxData = max(Data(:));
                MinData = min(Data(:));
                LimInd = max([abs(MinData) MaxData]);
                
                figs = findobj('Tag', 'heatmap');
                set(figs,'CLim',[-LimInd LimInd]);
                
                switch app.ColorScaleDropDown.Value
                    case 'Auto'
                        caxis([min(min(cell2mat(app.Database.Norm.Cell)))  max(min(cell2mat(app.Database.Norm.Cell)))])
                    case 'Manual'
                        caxis([app.YLimEditFieldMin.Value app.YLimEditFieldMax.Value])
                end
                
                if ~isempty(app.HeatmapFigureTitleEditField.Value)
                    sgtitle(fig,app.HeatmapFigureTitleEditField.Value)
                end
                HeatmapColorDropDownValueChanged(app, @HeatmapColorDropDownUpdate);
                MPAPASS_FigureProperties(app);
                fig.Visible = 'on';
                
            catch
                uialert(app.MPAPASS,'Failed to plot intensity heatmap','Plotting error');
                UpdateStatusWindow(app, [' | Error: Unable to plot heatmap'])
            end
            
            close(wait)
            
        end

        % Callback function: PlotTypeDropDown, RunPCAButton
        function RunPCAButtonPushed(app, event)
            % reset figure properties
            cla(app.UIAxes3)
            axis(app.UIAxes3,'square')
            app.UIAxes3.XTickLabel=('');
            app.UIAxes3.YTickLabel=('');
            app.UIAxes3.ZTickLabel=('');
            app.UIAxes3.BackgroundColor = [0.96 0.96 0.96];
            
            FigureWindowControl(app)    % close open windows depending on selection
            SampleIndex(app)            % updated sample selection index
            
            [Dataset] = app.Selection;   % scale dataset accordingly
            
            SampleNames = Dataset.SelectedInfo.(app.ColorbyDropDown_2.Items{app.ColorbyDropDown_2.Value});
            MarkerLabels = Dataset.SelectedRowNames;
            
            if size(Dataset.SelectedData, 2)  <= 5
                UpdateStatusWindow(app, [' | Error: Too few samples to perform PCA. Select more than five samples'])
                return
            end
            
            Data = Dataset.SelectedData;
            Data(isinf(Data))=0;
            Data(isnan(Data))=0;
            
            [app.PCA_Coeff, app.PCA_Score, ~, ~, app.PCA_Explained, ~] = pca(Data');
            
            switch app.PlotTypeDropDown.Value % 2D PCA Plot
                case '2D Scatter'
                    
                    %                     try
                    
                    
                    Draw_PCA_2DPlot(app)
                    
                    UpdateStatusWindow(app, [' | 2-D PCA plot complete'])
                    
                    %                     catch
                    
                    UpdateStatusWindow(app, [' | Error: Unable to plot 2-D PCA plot'])
                    
                    %                     end
                    
                case '2D Biplot'
                    % %                     try
                    hbi=biplot(app.UIAxes3, app.PCA_Coeff(:,1:2),'scores',app.PCA_Score(:,1:2),'varlabels',MarkerLabels,'obslabels',SampleNames,'markersize', 7.5 );
                    return
                    Col = lines(numel(unique(Group)));
                    coli = 1;
                    for ii = 1:length(hbi)
                        userdata = get(hbi(ii), 'Tag');
                        if contains(userdata,'obsmarker') == 1
                            set(hbi(ii), 'Color',Col(Group(coli),:), 'MarkerFaceColor',Col(Group(coli),:),'Marker', 'o');
                            coli = coli+1;
                        else
                        end
                    end
                    
                    xt = get(gca, 'XTick'); set(gca, 'FontSize', 16);
                    yt= get(gca,'YTick'); set(gca, 'FontSize', 16);
                    box on
                    
                    %                         UpdateStatusWindow(app, [' | 2-D PCA bi-plot complete'])
                    
                    %                     catch
                    
                    %                         UpdateStatusWindow(app, [' | Error: Unable to plot 2-D PCA bi-plot'])
                    
                    %                     end
                    
                    
                case '3D Scatter'
                    Draw_PCA_3DPlot(app)
                case '3D Biplot'
                    
                    
            end
            
            %             switch(app.D3PCAPlotSwitch.Value) % 3D PCA Plot
            %                 case 'Off'
            %                 case 'On'
            %                     try
            %                         figure;
            %
            %                         for ii=1:size(score,1)
            %                             h=scatter3(score(ii,1), score(ii,2), score(ii,3), 100, Col(Group(ii),:), 'filled');
            %                             customDataCursor(h, SampleNames(ii));
            %                             hold on
            %                         end
            %
            %                         xlabel('Component 1'); ylabel('Component 2'); zlabel('Component 3')
            %                         xt = get(gca, 'XTick'); set(gca, 'FontSize', 16);
            %                         yt= get(gca,'YTick'); set(gca, 'FontSize', 16);
            %
            %                         box on
            %                         axis tight
            %                         group_C = unique(Group, 'stable');
            %                         group_name = unique(GroupName, 'stable');
            %                         k = zeros(numel(group_C), 1);
            %
            %                         for j = 1:numel(k)
            %                             colour = Col(group_C(j),:);
            %                             k(j) = scatter(0,0,0.001, colour,'filled');
            %                         end
            %
            %
            %                         [~, objh] = legend(k, group_name);
            %
            %                         objhl = findobj(objh, 'type', 'line'); %// objects of legend of type line
            %                         set(objhl, 'Markersize', 100); %// set marker size as desired
            %
            %                         UpdateStatusWindow(app, [' | 3-D PCA plot complete'])
            %
            %                     catch
            %
            %                         UpdateStatusWindow(app, [' | Error: Unable to plot 3-D PCA plot'])
            %
            %                     end
            %
            %                     fig = gcf;
            %                     fig.Units='normalized';
            %                     fig.OuterPosition=[0 0 .4 .5];
            %
            %             end
            %
            %             switch(app.D2PCABiPlotSwitch.Value) % 2D PCA BiPlot
            %                 case 'Off'
            %                 case 'On'
            %
            % %                     try
            %                         figure;
            %                         hbi=biplot(coeff(:,1:2),'scores',score(:,1:2),'varlabels',MarkerLabels,'obslabels',SampleNames,'markersize', 7.5 );
            %
            %                         coli = 1;
            %                         for ii = 1:length(hbi)
            %                             userdata = get(hbi(ii), 'Tag');
            %                             if contains(userdata,'obsmarker') == 1
            %                                 set(hbi(ii), 'Color',Col(Group(coli),:), 'MarkerFaceColor',Col(Group(coli),:),'Marker', 'o');
            %                                 coli = coli+1;
            %                             else
            %                             end
            %                         end
            %
            %                         xt = get(gca, 'XTick'); set(gca, 'FontSize', 16);
            %                         yt= get(gca,'YTick'); set(gca, 'FontSize', 16);
            %                         box on
            %
            % %                         UpdateStatusWindow(app, [' | 2-D PCA bi-plot complete'])
            %
            % %                     catch
            %
            % %                         UpdateStatusWindow(app, [' | Error: Unable to plot 2-D PCA bi-plot'])
            %
            % %                     end
            %
            %
            %             end
            %
            %             switch(app.D3PCABiPlotSwitch.Value)
            %                 case 'Off'
            %                 case 'On'
            %
            %                     try
            %
            %                         figure;
            %                         hbi=biplot(coeff(:,1:3),'scores',score(:,1:3),'varlabels',MarkerLabels,'obslabels',SampleNames,'markersize', 7.5 );
            %
            %                         coli = 1;
            %                         for ii = 1:length(hbi)
            %                             userdata = get(hbi(ii), 'Tag');
            %                             if contains(userdata,'obsmarker') == 1
            %                                 set(hbi(ii), 'Color',Col(Group(coli),:), 'MarkerFaceColor',Col(Group(coli),:),'Marker', 'o');
            %                                 coli = coli+1;
            %                             else
            %                             end
            %                         end
            %
            %                         box on; axis tight
            %                         xt = get(gca, 'XTick'); set(gca, 'FontSize', 16);
            %                         yt = get(gca,'YTick'); set(gca, 'FontSize', 16);
            %
            %                         UpdateStatusWindow(app, [' | 3-D PCA bi-plot complete'])
            %
            %                     catch
            %
            %                         UpdateStatusWindow(app, [' | Error: Unable to plot 3-D PCA bi-plot'])
            %
            %                     end
            %
            %                     fig=gcf;
            %                     fig.Units='normalized';
            %                     fig.OuterPosition=[0 0 .4 .5];
            %             end
            %
            %             switch(app.ExportPCADataSwitch.Value)
            %                 case 'Off'
            %                 case 'On'
            %                     Names = SampleNames(:);
            %
            %                     PCA = table(Names, score);
            %
            %                     [file,path] = uiputfile('*.*');
            %                     path_file=fullfile(path,file);
            %                     disp(path_file)
            %                     path_file = [path_file,'.csv'];
            %                     writetable(PCA,path_file)
            %             end
            
            
        end

        % Value changed function: MixDropDown, SampleSetIDDropDown
        function RAWDataHistogramDropdownUpdate(app, event)
            PlotHistogramButtonPushed(app)
        end

        % Button pushed function: PlotBoxplotButton
        function PlotBoxPlotButtonValueChanged(app, event)
            FigureWindowControl(app)
            
            SampleIndex(app) % updated sample selection index
            d = uiprogressdlg(app.MPAPASS,'Title','Please Wait',...
                'Message','Plotting boxplot','Indeterminate','on');
            try
                [Dataset] = Dataset_scaling(app);
                Dataset.SelectedData(isinf(Dataset.SelectedData))=0;
                Dataset.SelectedData(isnan(Dataset.SelectedData))=0;
                
                BPInfo.Criteria = app.GroupSamplesbyDropDown_2.Items{app.GroupSamplesbyDropDown_2.Value};
                BPInfo.Spacing = 1-app.GroupSpacingSlider.Value;
                BPInfo.MarkerNumber = app.MarkerGroupNumberEditField.Value;
                BPInfo.Ranking = app.RankBoxplotDropDown.Value;
                BPInfo.RankingType = app.RankMethodDropDown.Value;
                BPInfo.MarkerLabels = MarkerLabelSelectionBoxPlot(app, Dataset);
                BPInfo.Orientation = app.OrientationDropDown.Value;
                BPInfo.Dividers = app.MarkerDividersDropDown.Value;
                BPInfo.Style = app.StyleDropDown.Value;
                BPInfo.FaceAlpha = 1;
                BPInfo.ColorOrder = getpref('MPAPASS','GlobalFigure_FigureColorOrder');
                MPA_boxplot(Dataset, BPInfo);
                [app] = MPAPASS_FigureProperties(app);
                UpdateStatusWindow(app, [' | Boxplot created'])
                
            catch
                uialert(app.MPAPASS,'Failed to plot intensity boxplot','Plotting error');
                
                UpdateStatusWindow(app, [' | Error: Unable to create Boxplot'])
                
            end
            close(d)
            
        end

        % Menu selected function: CloseoldfiguresMenu
        function CloseOldFiguresMenuSelected(app, event)
            if strcmp(app.CloseoldfiguresMenu.Checked,'on')
                app.CloseoldfiguresMenu.Checked = 'off';
            else
                app.CloseoldfiguresMenu.Checked = 'on';
            end
        end

        % Button pushed function: RuntSNEButton
        function RuntSNEButtonPushed(app, event)
            FigureWindowControl(app)
            
            Dataset = app.Selection;
            
            switch app.tSNEAnalysisButtonGroup.SelectedObject.Text
                case 'Markers'
                    if size(Dataset.SelectedData,1) <= 5
                        UpdateStatusWindow(app, [' | Error: number of samples is too low'])
                        return
                    else
                    end
                case 'Samples'
                    if size(Dataset.SelectedData,2) <= 5
                        UpdateStatusWindow(app, [' | Error: number of samples is too low'])
                        return
                    else
                    end
            end
            
            
            app.StopNow = false;
            
            switch app.DistanceMetricDropDown.Items{app.DistanceMetricDropDown.Value}
                case 'Euclidean'
                    dist = 'euclidean';
                case 'Chebychev'
                    dist = 'chebychev';
                case 'Minkowski'
                    dist = 'minkowski';
                case 'Mahalanobis'
                    dist = 'Mahalanobis';
                case 'Spearman'
                    dist = 'spearman';
            end
            
            switch app.AlgorithmButtonGroup.SelectedObject.Text
                case 'Exact'
                    alg = 'exact';
                case 'Barnes-Hut'
                    alg = 'barneshut';
            end
            
            if app.LearnRateEditField.Value == 0
                LR = 500;
            else
                LR = app.LearnRateEditField.Value;
            end
            
            
            if app.ExaggerationEditField.Value == 0
                Ex = 4;
            else
                Ex = app.ExaggerationEditField.Value;
            end
            
            switch app.tSNEAnalysisButtonGroup.SelectedObject.Text
                case 'Markers'
                    DatasetX = Dataset.SelectedData;
                case 'Samples'
                    DatasetX = Dataset.SelectedData';
            end
            
            
            DatasetX(isinf(DatasetX))=0;
            DatasetX(isnan(DatasetX))=0;
            
            app.tSNE_KLog = 0; % reset optimal KLog
            
            rng default % for reproducibility
            opts = statset('OutputFcn',@(optimValues,state) KLLogging(app, optimValues, state),'MaxIter',app.MaxIterEditField.Value);
            tsne(DatasetX,'Options',opts,...
                'Algorithm',alg,...
                'Distance',dist,...
                'LearnRate',LR,...
                'Exaggeration',Ex);
            
            UpdateStatusWindow(app, [' | tSNE Stopped'])
            
            
        end

        % Button pushed function: StoptSNEButton
        function StoptSNEButtonPushed(app, event)
            app.StopNow = true;
        end

        % Button pushed function: InteractivePlotButton
        function InteractivePlotButtonPushed(app, event)
            
            if isempty(app.tSNE_Y) == 1
                UpdateStatusWindow(app, [' | Run tSNE to create interactive plot data'])
                
            else
                LoopIndex = numel(unique(app.tSNE_GrpIndex));
                fig = figure;
                ax = axes;
                
                for i = 1:LoopIndex
                    app.tSNE_Plot = scatter(app.tSNE_Y(app.tSNE_GrpIndex==i,1), app.tSNE_Y(app.tSNE_GrpIndex==i,2), 100, app.tSNE_GColor(app.tSNE_GrpIndex==i,:), 'filled', 'Marker',app.tSNE_MarkerType{i}, 'MarkerFaceAlpha',.7,'MarkerEdgeColor','k');
                    hold on
                    switch class(app.tSNE_Index_Label)
                        case 'double'
                            label = cellstr(num2str(app.tSNE_Index_Label));
                            Names{i} = label{i};
                        case 'categorical'
                            app.tSNE_Index_Label = cellstr(app.tSNE_Index_Label);
                        otherwise
                            %                            switch class(IndexType)
                            Names{i} = app.tSNE_Index_Label{i};
                    end
                end
                
                hold(app.EmbeddingAxes,'off')
                
                box on
                axis square
                set(gca,'FontSize',12,'LineWidth',2,'TickLength', [0 0])
                xlabel('tSNE 1', 'FontSize',14, 'FontWeight', 'bold')
                ylabel('tSNE 2', 'FontSize',14, 'FontWeight', 'bold')
                xticklabels('')
                yticklabels('')
                Names = strrep(Names,'_',' ');
                
                [leg] = legend(Names,'Location',"eastoutside",'FontSize',14,'Box','off','NumColumns',ceil(numel(Names)/18));
                
                %                 objhl = findobj(objh, 'type', 'patch'); % objects of legend of type patch
                %                 set(objhl, 'Markersize', 11);
                
                
            end


        end

        % Button pushed function: PlotRecoveryMapButton
        function PlotRecoveryMapButtonPushed(app, event)
            FigureWindowControl(app)
            try
                Data = cell2mat(app.Database.SampleRecovery);
                figure('units','normalized','position',[0 0 1 1]);
                t = tiledlayout(1,1);
                t.TileSpacing = 'compact';
                t.Padding = 'compact';
                nexttile
                app.Database.Sample.Sample_Label_Mix_No
                SampleNames = strcat({'Set ID '}, char(app.Database.Sample.Sample_Set_ID), {', Mix No.'}, char(num2str(app.Database.Sample.Sample_Label_Mix_No)));
                heatmap2(Data, SampleNames, app.Database.Beads.Bead_CaptureAntibody_Target, [] ,'ShowAllTicks', true, 'TickAngle', 90,'GridLines', '-');
                set(gca, 'TickLength', [0 0])
                xtickangle(90)
                colormap turbo
                colorbar
                caxis([0 inf]);
            catch
                uialert(app.MPAPASS,'Failed to plot receovery heatmap','Plotting error');
            end
        end

        % Selection changed function: tSNEAnalysisButtonGroup
        function tSNEAnalysisButtonGroupSelectionChanged(app, event)
            
            switch app.tSNEAnalysisButtonGroup.SelectedObject.Text
                case 'Markers'
                    app.ColorbyDropDown.Items = horzcat(app.Database.Beads.Properties.VariableNames, app.Database.Labelling.Properties.VariableNames([1,3:end]));
                    
                case 'Samples'
                    app.ColorbyDropDown.Items =   app.Database.Sample.Properties.VariableNames([2:5,7:end]);
            end
            app.ColorbyDropDown.ItemsData = 1:numel(app.ColorbyDropDown.Items);
            ColorbyDropDownValueChanged(app, @ColorbyDropDownUpdate)
            
        end

        % Button pushed function: ClearPlotsButton
        function ClearPlotsButtonPushed(app, event)
            cla(app.EmbeddingAxes)
            cla(app.DevergenceAxes)
        end

        % Value changed function: ColorScaleDropDown
        function ColorScaleDropDownValueChanged(app, event)
            switch app.ColorScaleDropDown.Value
                case 'Auto'
                    app.YLimEditFieldMin.Visible = 'off';
                    %                     app.MinLabel.Visible = 'off';
                    app.YLimEditFieldMax.Visible = 'off';
                    %                     app.MaxLabel.Visible = 'off';
                case 'Manual'
                    app.YLimEditFieldMin.Visible = 'on';
                    %                     app.MinLabel.Visible = 'on';
                    app.YLimEditFieldMax.Visible = 'on';
                    %                     app.MaxLabel.Visible = 'on';
            end
        end

        % Selection changed function: XAxisButtonGroup
        function XAxisButtonGroupSelectionChanged(app, event)
            SampleIndexRawDataHistograms(app)
            PlotHistogramButtonPushed(app)
            
        end

        % Callback function
        function NewAnalysisMenuSelected(app, event)
            app.MPAPASS
        end

        % Callback function
        function Genboree_ExportMenuSelected(app, event)
            
        end

        % Value changed function: CustomHeadersListBox
        function CustomHeadersListBoxValueChanged(app, event)
            
            if app.CustomHeadersListBox.Value == 1
                % reset the custom variable selection
                for i = 1:length(app.Database.Sample.Properties.VariableNames(8:end))
                    app.CustomVariableIndex{i} = 1;
                end
                app.CustomVariablesListBox.Items = {'All'};
                app.CustomVariablesListBox.ItemsData = 1;
                UpdateStatusWindow(app, [' | Custom variable seleection reset'])
            else
                app.CustomVariablesListBox.Items = vertcat({'All'}, string(table2cell(unique(app.Database.Sample(:,app.CustomHeadersListBox.Value+5)),'stable')));
                app.CustomVariablesListBox.ItemsData = 1:numel(app.CustomVariablesListBox.Items);
                
                % select the saved item in the custom variables listbox
                if numel(app.CustomVariableIndex{app.CustomHeadersListBox.Value-1}) == size(app.Database.Sample,1)
                    app.CustomVariablesListBox.Value = 1 ;
                else
                    app.CustomVariablesListBox.Value = app.CustomVariableIndex{app.CustomHeadersListBox.Value-1};
                end
            end
            
        end

        % Value changed function: CustomVariablesListBox
        function CustomVariablesListBoxValueChanged(app, event)
            app.CustomVariableIndex{app.CustomHeadersListBox.Value-1} = app.CustomVariablesListBox.Value;
            SampleIndex(app)


        end

        % Value changed function: ColorbyDropDown
        function ColorbyDropDownValueChanged(app, event)
            
            switch app.tSNEAnalysisButtonGroup.SelectedObject.Text
                
                case 'Samples'
                    [UniqIndex,~,Index]=unique(app.Selection.SelectedInfo.((app.ColorbyDropDown.Items{app.ColorbyDropDown.Value})), 'stable');
                    
                    
                case 'Markers'
                    [UniqIndex,~,Index]=unique(app.Selection.SelectedLabelInfo.(app.ColorbyDropDown.Items{app.ColorbyDropDown.Value}),'stable');
                    
            end
            
            app.tSNE_Index_Label = UniqIndex;
            col = lines(numel(UniqIndex));
            app.tSNE_GColor = col(Index,:);
            app.tSNE_GrpIndex = Index;
            
            MarkersI = {'o','s','d','^','v','>','<','p'};
            if numel(UniqIndex) > numel(MarkersI)
                Markers = repmat(MarkersI, 1, ceil(numel(UniqIndex)/numel(MarkersI)));
                MarkerTypeIndex = Markers(1:numel(UniqIndex));
                app.tSNE_MarkerType = MarkerTypeIndex;
            else
                
                MarkerTypeIndex = MarkersI(1:numel(UniqIndex));
                app.tSNE_MarkerType = MarkerTypeIndex;
            end


            try
                app.tSNE_Plot.CData = app.tSNE_GColor;
            catch
            end
            if numel(app.tSNE_Y) > 1
                DrawtSNEPlot(app)
            else
            end
        end

        % Menu selected function: TestMenu
        function pushmybuttonButtonPushed(app, event)
            
            
            %             MPA_Database_Merge_v1()
            
        end

        % Value changed function: HeatmapColorDropDown
        function HeatmapColorDropDownValueChanged(app, event)
            
            try
                figs = findobj('Tag', 'heatmap');
                text = app.HeatmapColorDropDown.Items{app.HeatmapColorDropDown.Value};
                
                NLevels = app.ColorLevelsEditField.Value;
                
                for i = 1:numel(figs)
                    switch text
                        case 'red'
                            %                         figs(i).Parent.Colormap = cmocean('balance', 'pivot', 0);
                            set(figs(i),'Colormap', cmocean('balance',NLevels))
                        case 'jet'
                            figs(i).Parent.Colormap = jet(NLevels);
                        case 'parula'
                            figs(i).Parent.Colormap = parula(NLevels);
                        case 'copper'
                            figs(i).Parent.Colormap = copper(NLevels);
                        case 'hsv'
                            figs(i).Parent.Colormap = hsv(NLevels);
                        case 'cool'
                            figs(i).Parent.Colormap = cool(NLevels);
                        case 'spring'
                            figs(i).Parent.Colormap = spring(NLevels);
                        case 'winter'
                            figs(i).Parent.Colormap = winter(NLevels);
                        case 'summer'
                            figs(i).Parent.Colormap = summer(NLevels);
                        case 'spectral'
                            figs(i).Parent.Colormap = flipud(cbrewer('div','Spectral',NLevels));
                    end
                    
                end
            catch
            end
        end

        % Callback function
        function xdataDropDownValueChanged(app, event)
            
            %             [Dataset] = Dataset_scaling(app);
            
            xdata = Dataset.SelectedData(:, strcmp(Dataset.SelectedInfo.Sample_Set_ID, app.xdataSampleIDDropDown.Value));
            ydata = Dataset.SelectedData(:, strcmp(Dataset.SelectedInfo.Sample_Set_ID, app.ydataSampleIDDropDown.Value));
            scatter(app.UIAxes2, xdata, ydata)
            
        end

        % Menu selected function: NewAnalysisWindowMenu
        function New_Analysis_WindowMenuSelected(app, event)
            MPA_R2019a_v3;
        end

        % Callback function
        function PlotCorrelationButtonPushed(app, event)
            FigureWindowControl(app)
            [Dataset] = Dataset_scaling(app)  ;
            
            switch app.CorrelateDropDown.Value
                case 'Samples'
                    %                     [Dataset] = Dataset_scaling(app);
                    [R, ~] = corr(Dataset.SelectedData,'Type','Spearman');
                    SampleLabel = SampleLabelSelectionCorr(app, Dataset);
                    
                    switch app.ClusteringDropDown_2.Value
                        case 'off'
                            
                        otherwise
                            Y2 = pdist(R);
                            Z2 = linkage(Y2, app.ClusteringDropDown_2.Value);
                            Y2(isnan(Y2)) = 0;
                            leafOrder = optimalleaforder(Z2,Y2); % hierachical clustering of markers
                            R = R(leafOrder,leafOrder);
                            SampleLabel = SampleLabel(leafOrder);
                    end
                    
                    figure;
                    HM = axes;
                    heatmap2(R, SampleLabel, SampleLabel, [],'ShowAllTicks', true, 'TickAngle', 90,'GridLines', '-');
                    HM.Tag = 'heatmap';
                    axis square
                    xtickangle(90)
                    HeatmapColorDropDownValueChanged(app, @HeatmapColorDropDownUpdate)
                    set(gca,'TickLabelInterpreter', 'tex','TickLength',[0 0]);
                    caxis([-1 1 ])
                    cmocean('balance')
                    
                case 'Markers'
                    [R, ~] = corr(Dataset.SelectedData','Type','Spearman');
                    MarkerLabel = MarkerLabelSelectionCorr(app, Dataset);
                    
                    switch app.ClusteringDropDown_2.Value
                        case 'off'
                            
                        otherwise
                            Y2 = pdist(R);
                            Z2 = linkage(Y2, app.ClusteringDropDown_2.Value);
                            Y2(isnan(Y2)) = 0;
                            leafOrder = optimalleaforder(Z2,Y2); % hierachical clustering of markers
                            R = R(leafOrder,leafOrder);
                            MarkerLabel = MarkerLabel(leafOrder,:);
                    end
                    
                    disp('test')
                    figure;
                    HM = axes;
                    MPA_Heatmap_v1(R, MarkerLabel, MarkerLabel);
                    %                     HM.Tag = 'heatmap';
                    %                     axis square
                    %                     xtickangle(90);
                    %                     HeatmapColorDropDownValueChanged(app, @HeatmapColorDropDownUpdate)
                    %                     set(gca,'TickLabelInterpreter', 'tex','TickLength',[0 0]);
                    %                     caxis([-1 1 ]);
                    %                     cmocean('balance');
            end
        end

        % Button pushed function: InteractivePlotButton_2
        function InteractivePlotButton_2Pushed(app, event)
            FigureWindowControl(app)    % close open windows depending on selection
            switch app.PlotTypeDropDown.Value % 2D PCA Plot
                case '2D Scatter'
                    Export_PCA_2DPlot(app)
                case '3D Scatter'
                    Export_PCA_3DPlot(app)
            end
            
        end

        % Value changed function: ColorbyDropDown_2
        function ColorbyDropDown_2ValueChanged2(app, event)
            
            [UniqIndex,~,Index]=unique(app.Selection.SelectedInfo.((app.ColorbyDropDown_2.Items{app.ColorbyDropDown_2.Value})), 'stable');
            app.PCA_Index_Label = UniqIndex;
            col = lines(numel(UniqIndex));
            app.PCA_GColor = col(Index,:);
            app.PCA_GrpIndex = Index;
            
            MarkersI = {'o','s','d','^','v','>','<','*','p'};
            
            if numel(UniqIndex) > numel(MarkersI)
                Markers = repmat(MarkersI, 1, ceil(numel(UniqIndex)/numel(MarkersI)));
                MarkerTypeIndex = Markers(1:numel(UniqIndex));
                app.PCA_MarkerType = MarkerTypeIndex;
            else
                MarkerTypeIndex = MarkersI(1:numel(UniqIndex));
                app.PCA_MarkerType = MarkerTypeIndex;
            end
            
            if numel(app.PCA_Coeff) > 1
                switch app.PlotTypeDropDown.Value % 2D PCA Plot
                    case '2D Scatter'
                        Draw_PCA_2DPlot(app)
                    case '3D Scatter'
                        Draw_PCA_3DPlot(app)
                end
            else
            end
            
        end

        % Value changed function: ExportPCADataSwitch
        function ExportPCADataSwitchValueChanged(app, event)
            value = app.ExportPCADataSwitch.Value;
            
        end

        % Value changed function: BeadDropDown
        function BeadDropDownValueChanged(app, event)
            PlateRecovery(app)
            
        end

        % Value changed function: PlateNumberDropDown
        function PlateNumberDropDownValueChanged(app, event)
            PlateRecovery(app)
            
        end

        % Value changed function: MarkerLabelsListBox
        function MarkerLabelsListBoxValueChanged(app, event)
            value = app.MarkerLabelsListBox.Value;
            
        end

        % Value changed function: GroupSamplesbyDropDown
        function GroupSamplesbyDropDownValueChanged(app, event)
            value = app.GroupSamplesbyDropDown.Value;
            
        end

        % Menu selected function: MergeMenu
        function MergeDatasetsMenuSelected(app, event)
            
        end

        % Menu selected function: PreferencesMenu
        function PreferencesMenuSelected(app, event)
            
            d = uiprogressdlg(app.MPAPASS,'Title','Please wait',...
                'Message','Preferences open','Indeterminate','on');
            [state]=MPAPASS_Pref_GUI();
            waitfor(state)
            [app] = MPAPASS_Developer_Access(app);
            close(d)
        end

        % Button pushed function: ApplytofigureButton
        function ApplytofigureButtonPushed(app, event)
            
            [app] = MPAPASS_FigureProperties(app);
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            setpref('MPAPASS','GlobalFigure_LabelFontStyle',getpref('MPAPASS','GlobalFigure_defaultLabelFontStyle'))
            app.FontStyleDropDown.Items = listfonts;
            app.FontStyleDropDown.ItemsData = 1:numel(app.FontStyleDropDown.Items);
            app.FontStyleDropDown.Value = find(strcmp(app.FontStyleDropDown.Items, getpref('MPAPASS','GlobalFigure_defaultLabelFontStyle')));
            
            %% title font properties
            setpref('MPAPASS','GlobalFigure_TitleFontSize',getpref('MPAPASS','GlobalFigure_defaultTitleFontSize'))
            app.TitleFontEditField.Value = getpref('MPAPASS','GlobalFigure_defaultTitleFontSize');
            
            switch getpref('MPAPASS','GlobalFigure_defaultTitleFontBold')
                case 'on'
                    app.TitleFontBold.BackgroundColor = [0.80,0.80,0.80];
                case 'off'
                    app.TitleFontBold.BackgroundColor = [0.96,0.96,0.96];
            end
            
            
            %% label font properties
            setpref('MPAPASS','GlobalFigure_LabelFontSize',getpref('MPAPASS','GlobalFigure_defaultLabelFontSize'))
            app.LabelFontEditField.Value = getpref('MPAPASS','GlobalFigure_defaultLabelFontSize');
            
            switch getpref('MPAPASS','GlobalFigure_defaultLabelFontBold')
                case 'on'
                    app.LabelFontBold.BackgroundColor = [0.80,0.80,0.80];
                case 'off'
                    app.LabelFontBold.BackgroundColor = [0.96,0.96,0.96];
            end


            %% axis font properties
            setpref('MPAPASS','GlobalFigure_AxesFontSize',getpref('MPAPASS','GlobalFigure_defaultAxesFontSize'))
            app.AxisFontEditField.Value = getpref('MPAPASS','GlobalFigure_defaultAxesFontSize');
            
            switch getpref('MPAPASS','GlobalFigure_defaultAxesFontBold')
                case 'on'
                    app.AxisFontBold.BackgroundColor = [0.80,0.80,0.80];
                case 'off'
                    app.AxisFontBold.BackgroundColor = [0.96,0.96,0.96];
            end
            
            
            % figure properties
            setpref('MPAPASS','GlobalFigure_FigureOutputSize',getpref('MPAPASS','GlobalFigure_defaultFigureOutputSize'))
            app.OutputSizeDropDown.Value = getpref('MPAPASS','GlobalFigure_FigureOutputSize');
            Dims = getpref('MPAPASS','GlobalFigure_FigureOutputDim');
            app.FigureOutputXDimEditField.Value = Dims(1);
            app.FigureOutputYDimEditField.Value = Dims(2);
            
            % figure color order
            setpref('MPAPASS','GlobalFigure_FigureColorOrder',getpref('MPAPASS','GlobalFigure_defaultFigureColorOrder'))
            colors = getpref('MPAPASS','GlobalFigure_defaultFigureColorOrder');
            app.FigureColorOrder1.BackgroundColor = colors{1}(1,:);
            app.FigureColorOrder2.BackgroundColor = colors{1}(2,:);
            app.FigureColorOrder3.BackgroundColor = colors{1}(3,:);
            app.FigureColorOrder4.BackgroundColor = colors{1}(4,:);
            app.FigureColorOrder5.BackgroundColor = colors{1}(5,:);
            app.FigureColorOrder6.BackgroundColor = colors{1}(6,:);
            app.FigureColorOrder7.BackgroundColor = colors{1}(7,:);
            
        end

        % Value changed function: AxisFontEditField
        function AxisFontEditFieldValueChanged(app, event)
            setpref('MPAPASS','GlobalFigure_AxesFontSize',app.AxisFontEditField.Value)
        end

        % Value changed function: LabelFontEditField
        function LabelFontEditFieldValueChanged(app, event)
            setpref('MPAPASS','GlobalFigure_LabelFontSize',app.LabelFontEditField.Value)
        end

        % Value changed function: TitleFontEditField
        function TitleFontEditFieldValueChanged(app, event)
            setpref('MPAPASS','GlobalFigure_TitleFontSize',app.TitleFontEditField.Value)
        end

        % Button pushed function: TitleFontBold
        function TitleFontBoldButtonPushed(app, event)
            switch getpref('MPAPASS','GlobalFigure_TitleFontBold')
                case 'on'
                    setpref('MPAPASS','GlobalFigure_TitleFontBold','off')
                    app.TitleFontBold.BackgroundColor = [0.96,0.96,0.96];
                    
                case 'off'
                    setpref('MPAPASS','GlobalFigure_TitleFontBold','on')
                    app.TitleFontBold.BackgroundColor = [0.80,0.80,0.80];
            end
        end

        % Button pushed function: LabelFontBold
        function LabelFontBoldButtonPushed(app, event)
            switch getpref('MPAPASS','GlobalFigure_LabelFontBold')
                case 'on'
                    setpref('MPAPASS','GlobalFigure_LabelFontBold','off')
                    app.LabelFontBold.BackgroundColor = [0.96,0.96,0.96];
                    
                case 'off'
                    setpref('MPAPASS','GlobalFigure_LabelFontBold','on')
                    app.LabelFontBold.BackgroundColor = [0.80,0.80,0.80];
            end
            
        end

        % Button pushed function: AxisFontBold
        function AxisFontBoldButtonPushed(app, event)
            switch getpref('MPAPASS','GlobalFigure_AxesFontBold')
                case 'on'
                    setpref('MPAPASS','GlobalFigure_AxesFontBold','off')
                    app.AxisFontBold.BackgroundColor = [0.96,0.96,0.96];
                    
                case 'off'
                    setpref('MPAPASS','GlobalFigure_AxesFontBold','on')
                    app.AxisFontBold.BackgroundColor = [0.80,0.80,0.80];
            end
        end

        % Value changed function: FontStyleDropDown
        function FontStyleDropDownValueChanged(app, event)
            setpref('MPAPASS','GlobalFigure_LabelFontStyle', app.FontStyleDropDown.Items{app.FontStyleDropDown.Value})
            
        end

        % Value changed function: FigureOutputXDimEditField
        function FigureOutputXDimEditFieldValueChanged(app, event)
            setpref('MPAPASS','GlobalFigure_FigureOutputDim',[app.FigureOutputXDimEditField.Value app.FigureOutputYDimEditField.Value]);
            
        end

        % Value changed function: FigureOutputYDimEditField
        function FigureOutputYDimEditFieldValueChanged(app, event)
            setpref('MPAPASS','GlobalFigure_FigureOutputDim',[app.FigureOutputXDimEditField.Value app.FigureOutputYDimEditField.Value]);
            
        end

        % Value changed function: OutputSizeDropDown
        function OutputSizeDropDownValueChanged(app, event)
            setpref('MPAPASS','GlobalFigure_FigureOutputSize',app.OutputSizeDropDown.Value);
            
        end

        % Button pushed function: FigureColorOrder1
        function FigureColorOrder1ButtonPushed(app, event)
            colors = getpref('MPAPASS','GlobalFigure_FigureColorOrder');
            c = uisetcolor(colors{1}(1,:));
            app.FigureColorOrder1.BackgroundColor = c;
            colors{1}(1,:) = c;
            setpref('MPAPASS','GlobalFigure_FigureColorOrder', colors);
            
        end

        % Button pushed function: FigureColorOrder2
        function FigureColorOrder2ButtonPushed(app, event)
            colors = getpref('MPAPASS','GlobalFigure_FigureColorOrder');
            c = uisetcolor(colors{1}(2,:));
            app.FigureColorOrder2.BackgroundColor = c;
            colors{1}(2,:) = c;
            setpref('MPAPASS','GlobalFigure_FigureColorOrder', colors);
        end

        % Button pushed function: FigureColorOrder3
        function FigureColorOrder3ButtonPushed(app, event)
            colors = getpref('MPAPASS','GlobalFigure_FigureColorOrder');
            c = uisetcolor(colors{1}(3,:));
            app.FigureColorOrder3.BackgroundColor = c;
            colors{1}(3,:) = c;
            setpref('MPAPASS','GlobalFigure_FigureColorOrder', colors);
        end

        % Button pushed function: FigureColorOrder4
        function FigureColorOrder4ButtonPushed(app, event)
            colors = getpref('MPAPASS','GlobalFigure_FigureColorOrder');
            c = uisetcolor(colors{1}(4,:));
            app.FigureColorOrder4.BackgroundColor = c;
            colors{1}(4,:) = c;
            setpref('MPAPASS','GlobalFigure_FigureColorOrder', colors);
        end

        % Button pushed function: FigureColorOrder5
        function FigureColorOrder5ButtonPushed(app, event)
            colors = getpref('MPAPASS','GlobalFigure_FigureColorOrder');
            c = uisetcolor(colors{1}(5,:));
            app.FigureColorOrder5.BackgroundColor = c;
            colors{1}(5,:) = c;
            setpref('MPAPASS','GlobalFigure_FigureColorOrder', colors);
        end

        % Button pushed function: FigureColorOrder6
        function FigureColorOrder6ButtonPushed(app, event)
            colors = getpref('MPAPASS','GlobalFigure_FigureColorOrder');
            c = uisetcolor(colors{1}(6,:));
            app.FigureColorOrder6.BackgroundColor = c;
            colors{1}(6,:) = c;
            setpref('MPAPASS','GlobalFigure_FigureColorOrder', colors);
        end

        % Button pushed function: FigureColorOrder7
        function FigureColorOrder7ButtonPushed(app, event)
            colors = getpref('MPAPASS','GlobalFigure_FigureColorOrder');
            c = uisetcolor(colors{1}(7,:));
            app.FigureColorOrder7.BackgroundColor = c;
            colors{1}(7,:) = c;
            setpref('MPAPASS','GlobalFigure_FigureColorOrder', colors);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MPAPASS = uifigure('Visible', 'off');
            app.MPAPASS.Name = 'MPAPASS';
            app.Menu = uimenu(app.MPAPASS);
            app.Menu.Text = 'Menu';
            app.PreferencesMenu = uimenu(app.Menu);
            app.PreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @PreferencesMenuSelected, true);
            app.PreferencesMenu.Text = 'Preferences';
            app.DatasetMenu = uimenu(app.Menu);
            app.DatasetMenu.Text = 'Dataset';
            app.CreateMenu = uimenu(app.DatasetMenu);
            app.CreateMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateDatasetMenuSelected, true);
            app.CreateMenu.Accelerator = 'N';
            app.CreateMenu.Text = 'Create';
            app.LoadMenu = uimenu(app.DatasetMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDatasetMenuSelected, true);
            app.LoadMenu.Accelerator = 'L';
            app.LoadMenu.Text = 'Load';
            app.MergeMenu = uimenu(app.DatasetMenu);
            app.MergeMenu.MenuSelectedFcn = createCallbackFcn(app, @MergeDatasetsMenuSelected, true);
            app.MergeMenu.Accelerator = 'M';
            app.MergeMenu.Text = 'Merge';
            app.ExportMenu = uimenu(app.Menu);
            app.ExportMenu.Accelerator = 'E';
            app.ExportMenu.Text = 'Export';
            app.NormalizedDatasetMenu = uimenu(app.ExportMenu);
            app.NormalizedDatasetMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportNormDatasetMenuSelected, true);
            app.NormalizedDatasetMenu.Accelerator = 'E';
            app.NormalizedDatasetMenu.Text = 'Normalized Dataset';
            app.WindowsMenu = uimenu(app.MPAPASS);
            app.WindowsMenu.Text = 'Windows';
            app.CloseallwindowsMenu = uimenu(app.WindowsMenu);
            app.CloseallwindowsMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseallwindowsMenuSelected, true);
            app.CloseallwindowsMenu.Text = 'Close all windows';
            app.ClearstatuswindowMenu = uimenu(app.WindowsMenu);
            app.ClearstatuswindowMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearStatusWindowMenuSelected, true);
            app.ClearstatuswindowMenu.Text = 'Clear status window';
            app.CloseoldfiguresMenu = uimenu(app.WindowsMenu);
            app.CloseoldfiguresMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseOldFiguresMenuSelected, true);
            app.CloseoldfiguresMenu.Text = 'Close old figures';
            app.NewAnalysisWindowMenu = uimenu(app.WindowsMenu);
            app.NewAnalysisWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @New_Analysis_WindowMenuSelected, true);
            app.NewAnalysisWindowMenu.Text = 'New Analysis Window';
            app.TestMenu = uimenu(app.MPAPASS);
            app.TestMenu.MenuSelectedFcn = createCallbackFcn(app, @pushmybuttonButtonPushed, true);
            app.TestMenu.Text = 'Test';
            app.MainUITabs = uitabgroup(app.MPAPASS);
            app.SelectionTab = uitab(app.MainUITabs);
            app.SelectionTab.Title = 'Selection';
            app.AbMixesListBoxLabel = uilabel(app.SelectionTab);
            app.AbMixesListBoxLabel.Text = 'Ab Mixes';
            app.AbMixesListBox = uilistbox(app.SelectionTab);
            app.AbMixesListBox.Items = {'All'};
            app.AbMixesListBox.Multiselect = 'on';
            app.AbMixesListBox.ValueChangedFcn = createCallbackFcn(app, @AbMixesListBoxValueChanged, true);
            app.AbMixesListBox.Value = {'All'};
            app.CaptureAbListBoxLabel = uilabel(app.SelectionTab);
            app.CaptureAbListBoxLabel.Text = 'Capture Ab';
            app.CaptureAbListBox = uilistbox(app.SelectionTab);
            app.CaptureAbListBox.Items = {'All'};
            app.CaptureAbListBox.Multiselect = 'on';
            app.CaptureAbListBox.ValueChangedFcn = createCallbackFcn(app, @CaptureAbListBoxValueChanged, true);
            app.CaptureAbListBox.Value = {'All'};
            app.SamplesListBoxLabel = uilabel(app.SelectionTab);
            app.SamplesListBoxLabel.Text = 'Samples';
            app.SamplesListBox = uilistbox(app.SelectionTab);
            app.SamplesListBox.Items = {'All'};
            app.SamplesListBox.Multiselect = 'on';
            app.SamplesListBox.ValueChangedFcn = createCallbackFcn(app, @SamplesListBoxValueChanged, true);
            app.SamplesListBox.Value = {'All'};
            app.DetectionAbListBoxLabel = uilabel(app.SelectionTab);
            app.DetectionAbListBoxLabel.Text = 'Detection Ab';
            app.DetectionAbListBox = uilistbox(app.SelectionTab);
            app.DetectionAbListBox.Items = {'All'};
            app.DetectionAbListBox.Multiselect = 'on';
            app.DetectionAbListBox.ValueChangedFcn = createCallbackFcn(app, @DetectionAbListBoxValueChanged, true);
            app.DetectionAbListBox.Value = {'All'};
            app.GroupsListBoxLabel = uilabel(app.SelectionTab);
            app.GroupsListBoxLabel.Text = 'Groups';
            app.GroupsListBox = uilistbox(app.SelectionTab);
            app.GroupsListBox.Items = {'All'};
            app.GroupsListBox.Multiselect = 'on';
            app.GroupsListBox.ValueChangedFcn = createCallbackFcn(app, @GroupsListBoxValueChanged, true);
            app.GroupsListBox.Value = {'All'};
            app.CustomHeadersListBoxLabel = uilabel(app.SelectionTab);
            app.CustomHeadersListBoxLabel.Text = 'Custom Headers';
            app.CustomHeadersListBox = uilistbox(app.SelectionTab);
            app.CustomHeadersListBox.Items = {'Reset', ''};
            app.CustomHeadersListBox.ValueChangedFcn = createCallbackFcn(app, @CustomHeadersListBoxValueChanged, true);
            app.CustomHeadersListBox.Value = 'Reset';
            app.CustomVariablesListBox_2Label = uilabel(app.SelectionTab);
            app.CustomVariablesListBox_2Label.Text = 'Custom Variables';
            app.CustomVariablesListBox = uilistbox(app.SelectionTab);
            app.CustomVariablesListBox.Items = {'All', ''};
            app.CustomVariablesListBox.Multiselect = 'on';
            app.CustomVariablesListBox.ValueChangedFcn = createCallbackFcn(app, @CustomVariablesListBoxValueChanged, true);
            app.CustomVariablesListBox.Value = {'All'};
            app.DatabaseTab = uitab(app.MainUITabs);
            app.DatabaseTab.Title = 'Database';
            app.TabGroup5 = uitabgroup(app.DatabaseTab);
            app.SampleTab = uitab(app.TabGroup5);
            app.SampleTab.Title = 'Sample';
            app.UITable = uitable(app.SampleTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.LabellingTab = uitab(app.TabGroup5);
            app.LabellingTab.Title = 'Labelling';
            app.UITable2 = uitable(app.LabellingTab);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.QualityControlTab = uitab(app.MainUITabs);
            app.QualityControlTab.Title = 'Quality Control';
            app.TabGroup3 = uitabgroup(app.QualityControlTab);
            app.BeadRecoveryTab = uitab(app.TabGroup3);
            app.BeadRecoveryTab.Title = 'Bead Recovery';
            app.RecoveryHistogram = uiaxes(app.BeadRecoveryTab);
            title(app.RecoveryHistogram, 'Bead Recovery')
            xlabel(app.RecoveryHistogram, 'Bead Population Recovery')
            ylabel(app.RecoveryHistogram, 'Frequency')
            app.RecoveryHistogram.XTick = [];
            app.RecoveryHistogram.YTick = [];
            app.BeadsBelowThresholdListBoxLabel = uilabel(app.BeadRecoveryTab);
            app.BeadsBelowThresholdListBoxLabel.Text = 'Beads Below Threshold';
            app.BeadsBelowThresholdListBox = uilistbox(app.BeadRecoveryTab);
            app.BeadsBelowThresholdListBox.Items = {};
            app.BeadsBelowThresholdListBox.Value = {};
            app.TotalBeadsBelowThreshold = uieditfield(app.BeadRecoveryTab, 'numeric');
            app.TotalBeadsBelowThreshold.Editable = 'off';
            app.BeadRecoveryThresholdEditFieldLabel = uilabel(app.BeadRecoveryTab);
            app.BeadRecoveryThresholdEditFieldLabel.Text = 'Bead Recovery Threshold';
            app.BeadRecoveryThresholdEditField = uieditfield(app.BeadRecoveryTab, 'numeric');
            app.BeadRecoveryThresholdEditField.Limits = [1 Inf];
            app.BeadRecoveryThresholdEditField.ValueDisplayFormat = '%.0f';
            app.BeadRecoveryThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateRecoveryPlotButtonPushed, true);
            app.BeadRecoveryThresholdEditField.Value = 50;
            app.RawDataHistogramsTab = uitab(app.TabGroup3);
            app.RawDataHistogramsTab.Title = 'Raw Data Histograms';
            app.RawDataHist = uiaxes(app.RawDataHistogramsTab);
            xlabel(app.RawDataHist, 'Bead Intensity')
            ylabel(app.RawDataHist, 'Frequency')
            app.RawDataHist.XTick = [];
            app.RawDataHist.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'};
            app.RawDataHist.YTick = [];
            app.SampleSetIDDropDownLabel = uilabel(app.RawDataHistogramsTab);
            app.SampleSetIDDropDownLabel.Text = 'Sample Set ID';
            app.SampleSetIDDropDown = uidropdown(app.RawDataHistogramsTab);
            app.SampleSetIDDropDown.Items = {};
            app.SampleSetIDDropDown.ValueChangedFcn = createCallbackFcn(app, @RAWDataHistogramDropdownUpdate, true);
            app.SampleSetIDDropDown.Value = {};
            app.MixDropDownLabel = uilabel(app.RawDataHistogramsTab);
            app.MixDropDownLabel.Text = 'Mix';
            app.MixDropDown = uidropdown(app.RawDataHistogramsTab);
            app.MixDropDown.Items = {};
            app.MixDropDown.ValueChangedFcn = createCallbackFcn(app, @RAWDataHistogramDropdownUpdate, true);
            app.MixDropDown.Value = {};
            app.DetectionMarkerDropDownLabel = uilabel(app.RawDataHistogramsTab);
            app.DetectionMarkerDropDownLabel.Text = 'Detection Marker';
            app.DetectionMarkerDropDown = uidropdown(app.RawDataHistogramsTab);
            app.DetectionMarkerDropDown.Items = {};
            app.DetectionMarkerDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotHistogramButtonPushed, true);
            app.DetectionMarkerDropDown.Value = {};
            app.CaptureMarkerDropDownLabel = uilabel(app.RawDataHistogramsTab);
            app.CaptureMarkerDropDownLabel.Text = 'Capture Marker';
            app.CaptureMarkerDropDown = uidropdown(app.RawDataHistogramsTab);
            app.CaptureMarkerDropDown.Items = {};
            app.CaptureMarkerDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotHistogramButtonPushed, true);
            app.CaptureMarkerDropDown.Value = {};
            app.PlotHistogramButton = uibutton(app.RawDataHistogramsTab, 'push');
            app.PlotHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotHistogramButtonPushed, true);
            app.PlotHistogramButton.Text = 'Plot Histogram';
            app.XAxisButtonGroup = uibuttongroup(app.RawDataHistogramsTab);
            app.XAxisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @XAxisButtonGroupSelectionChanged, true);
            app.XAxisButtonGroup.Title = 'X Axis';
            app.Log10Button_2 = uitogglebutton(app.XAxisButtonGroup);
            app.Log10Button_2.Text = 'Log10';
            app.Log10Button_2.Value = true;
            app.LinearButton_2 = uitogglebutton(app.XAxisButtonGroup);
            app.LinearButton_2.Text = 'Linear';
            app.PlateRecoveryTab = uitab(app.TabGroup3);
            app.PlateRecoveryTab.Title = 'Plate Recovery';
            app.UIAxes4 = uiaxes(app.PlateRecoveryTab);
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.PlateNumberDropDownLabel = uilabel(app.PlateRecoveryTab);
            app.PlateNumberDropDownLabel.Text = 'Plate Number';
            app.PlateNumberDropDown = uidropdown(app.PlateRecoveryTab);
            app.PlateNumberDropDown.Items = {};
            app.PlateNumberDropDown.ValueChangedFcn = createCallbackFcn(app, @PlateNumberDropDownValueChanged, true);
            app.PlateNumberDropDown.Value = {};
            app.BeadDropDownLabel = uilabel(app.PlateRecoveryTab);
            app.BeadDropDownLabel.Text = 'Bead';
            app.BeadDropDown = uidropdown(app.PlateRecoveryTab);
            app.BeadDropDown.Items = {};
            app.BeadDropDown.ValueChangedFcn = createCallbackFcn(app, @BeadDropDownValueChanged, true);
            app.BeadDropDown.Value = {};
            app.IntensityplotsTab = uitab(app.MainUITabs);
            app.IntensityplotsTab.Title = 'Intensity plots';
            app.TabGroup8 = uitabgroup(app.IntensityplotsTab);
            app.HeatmapTab = uitab(app.TabGroup8);
            app.HeatmapTab.Title = 'Heatmap';
            app.SampleLabelsListBoxLabel = uilabel(app.HeatmapTab);
            app.SampleLabelsListBoxLabel.Text = 'Sample Labels';
            app.SampleLabelsListBox = uilistbox(app.HeatmapTab);
            app.SampleLabelsListBox.Items = {};
            app.SampleLabelsListBox.Multiselect = 'on';
            app.SampleLabelsListBox.Value = {};
            app.MarkerLabelsListBoxLabel = uilabel(app.HeatmapTab);
            app.MarkerLabelsListBoxLabel.Text = 'Marker Labels';
            app.MarkerLabelsListBox = uilistbox(app.HeatmapTab);
            app.MarkerLabelsListBox.Items = {};
            app.MarkerLabelsListBox.Multiselect = 'on';
            app.MarkerLabelsListBox.ValueChangedFcn = createCallbackFcn(app, @MarkerLabelsListBoxValueChanged, true);
            app.MarkerLabelsListBox.Value = {};
            app.TabGroup6 = uitabgroup(app.HeatmapTab);
            app.ClusteringTab = uitab(app.TabGroup6);
            app.ClusteringTab.Title = 'Clustering';
            app.ClusteringDropDownLabel = uilabel(app.ClusteringTab);
            app.ClusteringDropDownLabel.Text = 'Clustering';
            app.ClusteringDropDown = uidropdown(app.ClusteringTab);
            app.ClusteringDropDown.Items = {'No Clustering', 'Sample Clustering', 'Marker Clustering', 'Both'};
            app.ClusteringDropDown.Value = 'No Clustering';
            app.SampleOrderDropDownLabel = uilabel(app.ClusteringTab);
            app.SampleOrderDropDownLabel.Text = 'Sample Order';
            app.SampleOrderDropDown = uidropdown(app.ClusteringTab);
            app.SampleOrderDropDown.Items = {'Default', 'Group'};
            app.SampleOrderDropDown.Value = 'Default';
            app.GroupSamplesbyLabel = uilabel(app.ClusteringTab);
            app.GroupSamplesbyLabel.Text = {'Group Samples by'; ''};
            app.GroupSamplesbyDropDown = uidropdown(app.ClusteringTab);
            app.GroupSamplesbyDropDown.Items = {'', ''};
            app.GroupSamplesbyDropDown.ValueChangedFcn = createCallbackFcn(app, @GroupSamplesbyDropDownValueChanged, true);
            app.GroupSamplesbyDropDown.Value = '';
            app.LinkageDropDownLabel = uilabel(app.ClusteringTab);
            app.LinkageDropDownLabel.Text = 'Linkage';
            app.LinkageDropDown = uidropdown(app.ClusteringTab);
            app.LinkageDropDown.Items = {'Ward', 'Average', 'Complete', 'Centroid', 'Median', 'Weighted Average'};
            app.LinkageDropDown.Value = 'Average';
            app.MarkerOrderDropDownLabel = uilabel(app.ClusteringTab);
            app.MarkerOrderDropDownLabel.Text = 'Marker Order';
            app.MarkerOrderDropDown = uidropdown(app.ClusteringTab);
            app.MarkerOrderDropDown.Items = {'Default', 'Group'};
            app.MarkerOrderDropDown.Value = 'Default';
            app.GroupMarkersbyDropDownLabel = uilabel(app.ClusteringTab);
            app.GroupMarkersbyDropDownLabel.Text = 'Group Markers by';
            app.GroupMarkersbyDropDown = uidropdown(app.ClusteringTab);
            app.GroupMarkersbyDropDown.Items = {};
            app.GroupMarkersbyDropDown.Value = {};
            app.ColorSamplesbyDropDownLabel = uilabel(app.ClusteringTab);
            app.ColorSamplesbyDropDownLabel.Text = {'Color Samples by'; ''};
            app.ColorSamplesbyDropDown = uidropdown(app.ClusteringTab);
            app.ColorSamplesbyDropDown.Items = {'', ''};
            app.ColorSamplesbyDropDown.Value = '';
            app.OutputTab_3 = uitab(app.TabGroup6);
            app.OutputTab_3.Title = 'Output';
            app.HeatmapStyleDropDownLabel = uilabel(app.OutputTab_3);
            app.HeatmapStyleDropDownLabel.Text = 'Heatmap Style';
            app.HeatmapStyleDropDown = uidropdown(app.OutputTab_3);
            app.HeatmapStyleDropDown.Items = {'Classic', 'Circular'};
            app.HeatmapStyleDropDown.Value = 'Classic';
            app.MarkerNamesDropDownLabel = uilabel(app.OutputTab_3);
            app.MarkerNamesDropDownLabel.Text = 'Marker Names';
            app.MarkerNamesDropDown = uidropdown(app.OutputTab_3);
            app.MarkerNamesDropDown.Items = {'on', 'off'};
            app.MarkerNamesDropDown.Value = 'on';
            app.HeatmapColorDropDownLabel = uilabel(app.OutputTab_3);
            app.HeatmapColorDropDownLabel.Text = 'Heatmap Color';
            app.HeatmapColorDropDown = uidropdown(app.OutputTab_3);
            app.HeatmapColorDropDown.Items = {'spectral', 'red', 'parula', 'jet', 'copper', 'hsv', 'cool', 'spring', 'winter', 'summer'};
            app.HeatmapColorDropDown.ValueChangedFcn = createCallbackFcn(app, @HeatmapColorDropDownValueChanged, true);
            app.HeatmapColorDropDown.Value = 'red';
            app.GridLinesDropDownLabel = uilabel(app.OutputTab_3);
            app.GridLinesDropDownLabel.Text = 'Grid Lines';
            app.GridLinesDropDown = uidropdown(app.OutputTab_3);
            app.GridLinesDropDown.Items = {'on', 'off'};
            app.GridLinesDropDown.Value = 'on';
            app.YLimEditFieldMin = uieditfield(app.OutputTab_3, 'numeric');
            app.YLimEditFieldMax = uieditfield(app.OutputTab_3, 'numeric');
            app.YLimEditFieldMax.Value = 1;
            app.ColorScaleDropDownLabel = uilabel(app.OutputTab_3);
            app.ColorScaleDropDownLabel.Text = {'Color Scale'; ''};
            app.ColorScaleDropDown = uidropdown(app.OutputTab_3);
            app.ColorScaleDropDown.Items = {'Max Dataset', 'Max Selection', 'Manual'};
            app.ColorScaleDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorScaleDropDownValueChanged, true);
            app.ColorScaleDropDown.Value = 'Max Dataset';
            app.AspectRatioDropDownLabel = uilabel(app.OutputTab_3);
            app.AspectRatioDropDownLabel.Text = 'Aspect Ratio';
            app.AspectRatioDropDown = uidropdown(app.OutputTab_3);
            app.AspectRatioDropDown.Items = {'on', 'off'};
            app.AspectRatioDropDown.Value = 'off';
            app.ColorLevelsEditFieldLabel = uilabel(app.OutputTab_3);
            app.ColorLevelsEditFieldLabel.Text = 'Color Levels';
            app.ColorLevelsEditField = uieditfield(app.OutputTab_3, 'numeric');
            app.ColorLevelsEditField.Value = 55;
            app.FigureTitleEditFieldLabel = uilabel(app.OutputTab_3);
            app.FigureTitleEditFieldLabel.Text = 'Figure Title';
            app.HeatmapFigureTitleEditField = uieditfield(app.OutputTab_3, 'text');
            app.BoxplotTab = uitab(app.TabGroup8);
            app.BoxplotTab.Title = 'Boxplot';
            app.GroupSpacingSliderLabel = uilabel(app.BoxplotTab);
            app.GroupSpacingSliderLabel.Text = 'Group Spacing';
            app.GroupSpacingSlider = uislider(app.BoxplotTab);
            app.GroupSpacingSlider.Limits = [0.01 1];
            app.GroupSpacingSlider.MajorTicks = [0.01 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.GroupSpacingSlider.MajorTickLabels = {'', '', '', ''};
            app.GroupSpacingSlider.MinorTicks = 0;
            app.GroupSpacingSlider.Value = 0.2;
            app.GroupColourDropDownLabel = uilabel(app.BoxplotTab);
            app.GroupColourDropDownLabel.Text = 'Group Colour';
            app.GroupColourDropDown = uidropdown(app.BoxplotTab);
            app.GroupColourDropDown.Items = {'Line', 'HSV', 'Jet', 'Prism'};
            app.GroupColourDropDown.Value = 'Line';
            app.OrientationDropDownLabel = uilabel(app.BoxplotTab);
            app.OrientationDropDownLabel.Text = 'Orientation';
            app.OrientationDropDown = uidropdown(app.BoxplotTab);
            app.OrientationDropDown.Items = {'Portrait', 'Landscape'};
            app.OrientationDropDown.Value = 'Landscape';
            app.MarkerDividersDropDownLabel = uilabel(app.BoxplotTab);
            app.MarkerDividersDropDownLabel.Text = 'Marker Dividers';
            app.MarkerDividersDropDown = uidropdown(app.BoxplotTab);
            app.MarkerDividersDropDown.Items = {'On', 'Off'};
            app.MarkerDividersDropDown.Value = 'On';
            app.GroupSamplesbyDropDown_2Label = uilabel(app.BoxplotTab);
            app.GroupSamplesbyDropDown_2Label.Text = 'Group Samples by';
            app.GroupSamplesbyDropDown_2 = uidropdown(app.BoxplotTab);
            app.GroupSamplesbyDropDown_2.Items = {};
            app.GroupSamplesbyDropDown_2.Value = {};
            app.MarkerGroupNumberEditFieldLabel = uilabel(app.BoxplotTab);
            app.MarkerGroupNumberEditFieldLabel.Tooltip = {'0 will display all markers'};
            app.MarkerGroupNumberEditFieldLabel.Text = 'Marker Group Number';
            app.MarkerGroupNumberEditField = uieditfield(app.BoxplotTab, 'numeric');
            app.MarkerGroupNumberEditField.Limits = [0 150];
            app.RankBoxplotDropDownLabel = uilabel(app.BoxplotTab);
            app.RankBoxplotDropDownLabel.Text = 'Rank Boxplot';
            app.RankBoxplotDropDown = uidropdown(app.BoxplotTab);
            app.RankBoxplotDropDown.Items = {'on', 'off'};
            app.RankBoxplotDropDown.Value = 'off';
            app.RankMethodDropDownLabel = uilabel(app.BoxplotTab);
            app.RankMethodDropDownLabel.Text = 'Rank Method';
            app.RankMethodDropDown = uidropdown(app.BoxplotTab);
            app.RankMethodDropDown.Items = {'range', 'significance', 'expression'};
            app.RankMethodDropDown.Value = 'significance';
            app.StyleDropDownLabel = uilabel(app.BoxplotTab);
            app.StyleDropDownLabel.Text = 'Style';
            app.StyleDropDown = uidropdown(app.BoxplotTab);
            app.StyleDropDown.Items = {'Box', 'Box & Whisker', 'Scatter', 'Box + Scatter'};
            app.StyleDropDown.Value = 'Box + Scatter';
            app.MarkerLabelsListBox_2Label = uilabel(app.BoxplotTab);
            app.MarkerLabelsListBox_2Label.Text = 'Marker Labels';
            app.MarkerLabelsListBox_2 = uilistbox(app.BoxplotTab);
            app.MarkerLabelsListBox_2.Items = {};
            app.MarkerLabelsListBox_2.Multiselect = 'on';
            app.MarkerLabelsListBox_2.Value = {};
            app.FigureTitleEditField_2Label = uilabel(app.BoxplotTab);
            app.FigureTitleEditField_2Label.Text = 'Figure Title';
            app.BoxPlotFigureTitleEditField = uieditfield(app.BoxplotTab, 'text');
            app.ClusteringTab_2 = uitab(app.MainUITabs);
            app.ClusteringTab_2.Title = 'Clustering';
            app.TabGroup7 = uitabgroup(app.ClusteringTab_2);
            app.PCATab_2 = uitab(app.TabGroup7);
            app.PCATab_2.Title = 'PCA';
            app.UIAxes3 = uiaxes(app.PCATab_2);
            title(app.UIAxes3, 'PCA Plot')
            xlabel(app.UIAxes3, 'Component 1')
            ylabel(app.UIAxes3, 'Component 2')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.RunPCAButton = uibutton(app.PCATab_2, 'push');
            app.RunPCAButton.ButtonPushedFcn = createCallbackFcn(app, @RunPCAButtonPushed, true);
            app.RunPCAButton.Text = 'Run PCA';
            app.InteractivePlotButton_2 = uibutton(app.PCATab_2, 'push');
            app.InteractivePlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @InteractivePlotButton_2Pushed, true);
            app.InteractivePlotButton_2.Text = 'Interactive Plot';
            app.ExportPCADataSwitchLabel = uilabel(app.PCATab_2);
            app.ExportPCADataSwitchLabel.Text = 'Export PCA Data';
            app.ExportPCADataSwitch = uiswitch(app.PCATab_2, 'slider');
            app.ExportPCADataSwitch.ValueChangedFcn = createCallbackFcn(app, @ExportPCADataSwitchValueChanged, true);
            app.ColorbyDropDown_2Label = uilabel(app.PCATab_2);
            app.ColorbyDropDown_2Label.Text = 'Color by:';
            app.ColorbyDropDown_2 = uidropdown(app.PCATab_2);
            app.ColorbyDropDown_2.Items = {};
            app.ColorbyDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ColorbyDropDown_2ValueChanged2, true);
            app.ColorbyDropDown_2.Value = {};
            app.PlotTypeDropDownLabel = uilabel(app.PCATab_2);
            app.PlotTypeDropDownLabel.Text = 'Plot Type';
            app.PlotTypeDropDown = uidropdown(app.PCATab_2);
            app.PlotTypeDropDown.Items = {'2D Scatter', '2D Biplot', '3D Scatter', '3D Biplot'};
            app.PlotTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @RunPCAButtonPushed, true);
            app.PlotTypeDropDown.Value = '2D Scatter';
            app.tSNETab_2 = uitab(app.TabGroup7);
            app.tSNETab_2.Title = 'tSNE';
            app.EmbeddingAxes = uiaxes(app.tSNETab_2);
            title(app.EmbeddingAxes, 'Embedding')
            xlabel(app.EmbeddingAxes, 'tSNE-1')
            ylabel(app.EmbeddingAxes, 'tSNE-2')
            app.EmbeddingAxes.XTick = [];
            app.EmbeddingAxes.YTick = [];
            app.DevergenceAxes = uiaxes(app.tSNETab_2);
            title(app.DevergenceAxes, 'Fit')
            xlabel(app.DevergenceAxes, 'Iterations')
            app.DevergenceAxes.XTick = [];
            app.DevergenceAxes.YTick = [];
            app.ClearPlotsButton = uibutton(app.tSNETab_2, 'push');
            app.ClearPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotsButtonPushed, true);
            app.ClearPlotsButton.Text = 'Clear Plots';
            app.tSNEAnalysisButtonGroup = uibuttongroup(app.tSNETab_2);
            app.tSNEAnalysisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @tSNEAnalysisButtonGroupSelectionChanged, true);
            app.tSNEAnalysisButtonGroup.Title = 'tSNE Analysis';
            app.SamplesButton = uitogglebutton(app.tSNEAnalysisButtonGroup);
            app.SamplesButton.Text = 'Samples';
            app.SamplesButton.Value = true;
            app.MarkersButton = uitogglebutton(app.tSNEAnalysisButtonGroup);
            app.MarkersButton.Text = 'Markers';
            app.RuntSNEButton = uibutton(app.tSNETab_2, 'push');
            app.RuntSNEButton.ButtonPushedFcn = createCallbackFcn(app, @RuntSNEButtonPushed, true);
            app.RuntSNEButton.Text = 'Run tSNE';
            app.StoptSNEButton = uibutton(app.tSNETab_2, 'push');
            app.StoptSNEButton.ButtonPushedFcn = createCallbackFcn(app, @StoptSNEButtonPushed, true);
            app.StoptSNEButton.Text = 'Stop tSNE';
            app.InteractivePlotButton = uibutton(app.tSNETab_2, 'push');
            app.InteractivePlotButton.ButtonPushedFcn = createCallbackFcn(app, @InteractivePlotButtonPushed, true);
            app.InteractivePlotButton.Text = 'Interactive Plot';
            app.AlgorithmButtonGroup = uibuttongroup(app.tSNETab_2);
            app.AlgorithmButtonGroup.Title = 'Algorithm';
            app.ExactButton = uitogglebutton(app.AlgorithmButtonGroup);
            app.ExactButton.Text = 'Exact';
            app.ExactButton.Value = true;
            app.BarnesHutButton = uitogglebutton(app.AlgorithmButtonGroup);
            app.BarnesHutButton.Text = 'Barnes-Hut';
            app.ExaggerationEditFieldLabel = uilabel(app.tSNETab_2);
            app.ExaggerationEditFieldLabel.Text = 'Exaggeration';
            app.ExaggerationEditField = uieditfield(app.tSNETab_2, 'numeric');
            app.ExaggerationEditField.Limits = [0 Inf];
            app.ExaggerationEditField.ValueDisplayFormat = '%.0f';
            app.ExaggerationEditField.Tooltip = {'Using a value of 0 uses the default Exaggeration value.'};
            app.PerplexityLabel = uilabel(app.tSNETab_2);
            app.PerplexityLabel.Text = 'Perplexity';
            app.PerplexityEditField = uieditfield(app.tSNETab_2, 'numeric');
            app.PerplexityEditField.Limits = [0 Inf];
            app.PerplexityEditField.ValueDisplayFormat = '%.0f';
            app.PerplexityEditField.Tooltip = {'Using a value of 0 uses the default Perplexity value.'};
            app.LearnRateEditFieldLabel = uilabel(app.tSNETab_2);
            app.LearnRateEditFieldLabel.Text = 'Learn Rate';
            app.LearnRateEditField = uieditfield(app.tSNETab_2, 'numeric');
            app.LearnRateEditField.Limits = [0 Inf];
            app.LearnRateEditField.ValueDisplayFormat = '%.0f';
            app.LearnRateEditField.Tooltip = {'Learning rate between 10-1000 recommended.'};
            app.LearnRateEditField.Value = 500;
            app.DistanceMetricDropDownLabel = uilabel(app.tSNETab_2);
            app.DistanceMetricDropDownLabel.Text = 'Distance Metric';
            app.DistanceMetricDropDown = uidropdown(app.tSNETab_2);
            app.DistanceMetricDropDown.Items = {'Euclidean', 'Chebychev', 'Minkowski', 'Mahalanobis', 'Spearman'};
            app.DistanceMetricDropDown.Value = 'Euclidean';
            app.ColorbyDropDownLabel = uilabel(app.tSNETab_2);
            app.ColorbyDropDownLabel.Text = 'Color by:';
            app.ColorbyDropDown = uidropdown(app.tSNETab_2);
            app.ColorbyDropDown.Items = {};
            app.ColorbyDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorbyDropDownValueChanged, true);
            app.ColorbyDropDown.Value = {};
            app.MaxIterEditFieldLabel = uilabel(app.tSNETab_2);
            app.MaxIterEditFieldLabel.Text = 'Max Iter';
            app.MaxIterEditField = uieditfield(app.tSNETab_2, 'numeric');
            app.MaxIterEditField.Limits = [1000 Inf];
            app.MaxIterEditField.ValueDisplayFormat = '%.0f';
            app.MaxIterEditField.Value = 100000;
            app.FigurePropertiesTab = uitab(app.MainUITabs);
            app.FigurePropertiesTab.Title = 'Figure Properties';
            app.TitleFontBold = uibutton(app.FigurePropertiesTab, 'push');
            app.TitleFontBold.ButtonPushedFcn = createCallbackFcn(app, @TitleFontBoldButtonPushed, true);
            app.TitleFontBold.Text = 'B';
            app.FontPropertiesLabel = uilabel(app.FigurePropertiesTab);
            app.FontPropertiesLabel.Text = 'Font Properties';
            app.TitleFontEditFieldLabel = uilabel(app.FigurePropertiesTab);
            app.TitleFontEditFieldLabel.Text = 'Title Font';
            app.TitleFontEditField = uieditfield(app.FigurePropertiesTab, 'numeric');
            app.TitleFontEditField.ValueChangedFcn = createCallbackFcn(app, @TitleFontEditFieldValueChanged, true);
            app.LabelFontBold = uibutton(app.FigurePropertiesTab, 'push');
            app.LabelFontBold.ButtonPushedFcn = createCallbackFcn(app, @LabelFontBoldButtonPushed, true);
            app.LabelFontBold.Text = 'B';
            app.LabelFontEditFieldLabel = uilabel(app.FigurePropertiesTab);
            app.LabelFontEditFieldLabel.Text = 'Label Font';
            app.LabelFontEditField = uieditfield(app.FigurePropertiesTab, 'numeric');
            app.LabelFontEditField.ValueChangedFcn = createCallbackFcn(app, @LabelFontEditFieldValueChanged, true);
            app.AxisFontBold = uibutton(app.FigurePropertiesTab, 'push');
            app.AxisFontBold.ButtonPushedFcn = createCallbackFcn(app, @AxisFontBoldButtonPushed, true);
            app.AxisFontBold.Text = 'B';
            app.AxisFontEditFieldLabel = uilabel(app.FigurePropertiesTab);
            app.AxisFontEditFieldLabel.Text = 'Axis Font';
            app.AxisFontEditField = uieditfield(app.FigurePropertiesTab, 'numeric');
            app.AxisFontEditField.ValueChangedFcn = createCallbackFcn(app, @AxisFontEditFieldValueChanged, true);
            app.FontStyleDropDownLabel = uilabel(app.FigurePropertiesTab);
            app.FontStyleDropDownLabel.Text = 'Font Style';
            app.FontStyleDropDown = uidropdown(app.FigurePropertiesTab);
            app.FontStyleDropDown.Items = {};
            app.FontStyleDropDown.ValueChangedFcn = createCallbackFcn(app, @FontStyleDropDownValueChanged, true);
            app.FontStyleDropDown.Value = {};
            app.FigurePropertiesLabel = uilabel(app.FigurePropertiesTab);
            app.FigurePropertiesLabel.Text = 'Figure Properties';
            app.OutputSizeDropDownLabel = uilabel(app.FigurePropertiesTab);
            app.OutputSizeDropDownLabel.Text = 'Output Size';
            app.OutputSizeDropDown = uidropdown(app.FigurePropertiesTab);
            app.OutputSizeDropDown.Items = {'Auto', 'Manual'};
            app.OutputSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputSizeDropDownValueChanged, true);
            app.OutputSizeDropDown.Value = 'Auto';
            app.WidthHeightpixelsLabel = uilabel(app.FigurePropertiesTab);
            app.WidthHeightpixelsLabel.Text = 'Width | Height [pixels]';
            app.FigureOutputYDimEditField = uieditfield(app.FigurePropertiesTab, 'numeric');
            app.FigureOutputYDimEditField.ValueChangedFcn = createCallbackFcn(app, @FigureOutputYDimEditFieldValueChanged, true);
            app.FigureOutputXDimEditField = uieditfield(app.FigurePropertiesTab, 'numeric');
            app.FigureOutputXDimEditField.ValueChangedFcn = createCallbackFcn(app, @FigureOutputXDimEditFieldValueChanged, true);
            app.ApplytofigureButton = uibutton(app.FigurePropertiesTab, 'push');
            app.ApplytofigureButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytofigureButtonPushed, true);
            app.ApplytofigureButton.Text = 'Apply to figure';
            app.DefaultButton = uibutton(app.FigurePropertiesTab, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.ColorOrderLabel = uilabel(app.FigurePropertiesTab);
            app.ColorOrderLabel.Text = 'Color Order';
            app.FigureColorOrder1 = uibutton(app.FigurePropertiesTab, 'push');
            app.FigureColorOrder1.ButtonPushedFcn = createCallbackFcn(app, @FigureColorOrder1ButtonPushed, true);
            app.FigureColorOrder1.Text = '';
            app.FigureColorOrder2 = uibutton(app.FigurePropertiesTab, 'push');
            app.FigureColorOrder2.ButtonPushedFcn = createCallbackFcn(app, @FigureColorOrder2ButtonPushed, true);
            app.FigureColorOrder2.Text = '';
            app.FigureColorOrder3 = uibutton(app.FigurePropertiesTab, 'push');
            app.FigureColorOrder3.ButtonPushedFcn = createCallbackFcn(app, @FigureColorOrder3ButtonPushed, true);
            app.FigureColorOrder3.Text = '';
            app.FigureColorOrder4 = uibutton(app.FigurePropertiesTab, 'push');
            app.FigureColorOrder4.ButtonPushedFcn = createCallbackFcn(app, @FigureColorOrder4ButtonPushed, true);
            app.FigureColorOrder4.Text = '';
            app.FigureColorOrder5 = uibutton(app.FigurePropertiesTab, 'push');
            app.FigureColorOrder5.ButtonPushedFcn = createCallbackFcn(app, @FigureColorOrder5ButtonPushed, true);
            app.FigureColorOrder5.Text = '';
            app.FigureColorOrder6 = uibutton(app.FigurePropertiesTab, 'push');
            app.FigureColorOrder6.ButtonPushedFcn = createCallbackFcn(app, @FigureColorOrder6ButtonPushed, true);
            app.FigureColorOrder6.Text = '';
            app.FigureColorOrder7 = uibutton(app.FigurePropertiesTab, 'push');
            app.FigureColorOrder7.ButtonPushedFcn = createCallbackFcn(app, @FigureColorOrder7ButtonPushed, true);
            app.FigureColorOrder7.Text = '';
            app.LogTab = uitab(app.MainUITabs);
            app.LogTab.Title = 'Log';
            app.CommandWindowListBox = uilistbox(app.LogTab);
            app.CommandWindowListBox.Items = {};
            app.CommandWindowListBox.Value = {};
            app.ThresholdStatisticDropDownLabel = uilabel(app.MPAPASS);
            app.ThresholdStatisticDropDownLabel.Text = 'Threshold Statistic';
            app.ThresholdStatisticDropDown = uidropdown(app.MPAPASS);
            app.ThresholdStatisticDropDown.Items = {'Minimum', 'Median', 'Mean', 'Max'};
            app.ThresholdStatisticDropDown.Value = 'Minimum';
            app.ThresholdDropDownLabel = uilabel(app.MPAPASS);
            app.ThresholdDropDownLabel.Text = 'Threshold';
            app.ThresholdDropDown = uidropdown(app.MPAPASS);
            app.ThresholdDropDown.Items = {'On', 'Off'};
            app.ThresholdDropDown.Value = 'Off';
            app.NormaliseDatasetButton = uibutton(app.MPAPASS, 'push');
            app.NormaliseDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @NormaliseDatasetButtonPushed, true);
            app.NormaliseDatasetButton.Text = 'Normalise Dataset';
            app.DatasetScalingDropDownLabel = uilabel(app.MPAPASS);
            app.DatasetScalingDropDownLabel.Text = 'Dataset Scaling';
            app.DatasetScalingDropDown = uidropdown(app.MPAPASS);
            app.DatasetScalingDropDown.Items = {'Log10', 'Log2', 'Log', 'Linear'};
            app.DatasetScalingDropDown.Value = 'Log10';
            app.NormalisationDropDownLabel = uilabel(app.MPAPASS);
            app.NormalisationDropDownLabel.Text = 'Normalisation';
            app.NormalisationDropDown = uidropdown(app.MPAPASS);
            app.NormalisationDropDown.Items = {'Background Subtraction', 'Stain Index 1', 'Stain Index 2', 'Fold Change'};
            app.NormalisationDropDown.Value = 'Fold Change';
            app.PlotIntensityMapButton = uibutton(app.MPAPASS, 'push');
            app.PlotIntensityMapButton.ButtonPushedFcn = createCallbackFcn(app, @PlotIntensityMapButtonPushed, true);
            app.PlotIntensityMapButton.Text = 'Plot Intensity Map';
            app.PlotBoxplotButton = uibutton(app.MPAPASS, 'push');
            app.PlotBoxplotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotBoxPlotButtonValueChanged, true);
            app.PlotBoxplotButton.Text = 'Plot Boxplot';
            app.PlotRecoveryMapButton = uibutton(app.MPAPASS, 'push');
            app.PlotRecoveryMapButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRecoveryMapButtonPushed, true);
            app.PlotRecoveryMapButton.Text = 'Plot Recovery Map';
            app.ThresholdEditFieldLabel = uilabel(app.MPAPASS);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.MPAPASS, 'numeric');
            app.Image = uiimage(app.MPAPASS);
            app.Image.ImageSource = 'MPx Logo 256.jpeg';
            app.Lamp = uilamp(app.MPAPASS);
            % Show the figure after all components are created
            app.MPAPASS.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MPA_Master_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MPAPASS)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MPAPASS)
        end
    end
end