classdef Import_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FileImportUIFigure          matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        NewDirectoryMenu            matlab.ui.container.Menu
        QuitMenu                    matlab.ui.container.Menu
        EditMenu                    matlab.ui.container.Menu
        ClearStatusWindowMenu       matlab.ui.container.Menu
        BeadTable                   matlab.ui.control.Table
        ImportDatabaseButton        matlab.ui.control.Button
        ImportDataButton            matlab.ui.control.Button
        DatabaseQCButton            matlab.ui.control.Button
        ConstuctDatabaseButton      matlab.ui.control.Button
        SelectCSVDirectoryButton    matlab.ui.control.Button
        CommandWindowListBox        matlab.ui.control.ListBox
        TabGroup                    matlab.ui.container.TabGroup
        BeadInfoTab                 matlab.ui.container.Tab
        RemovePresetButton          matlab.ui.control.Button
        RemovePopulationButton      matlab.ui.control.Button
        SavePresetButton            matlab.ui.control.Button
        PresetBeadsDropDown         matlab.ui.control.DropDown
        BeadIsotypeListBox          matlab.ui.control.ListBox
        BeadMarkerListBox           matlab.ui.control.ListBox
        AddPopulationButton         matlab.ui.control.Button
        IsotypeEditField            matlab.ui.control.EditField
        IsotypeEditFieldLabel       matlab.ui.control.Label
        IdentifierEditField         matlab.ui.control.NumericEditField
        IdentifierEditFieldLabel    matlab.ui.control.Label
        MarkerEditField             matlab.ui.control.EditField
        MarkerEditFieldLabel        matlab.ui.control.Label
        BeadIdentifierListBox       matlab.ui.control.ListBox
        DirectoryFilesListBox       matlab.ui.control.ListBox
        DirectoryFilesListBoxLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        Str_List = [];          % Description
        new_dir = [];           % directory folder
        GroupName = [];
        Sample_Filename = [];
        Sample_Name = [];
        R_Group = [];
        Control_Filename = [];
        DataX = [];
        Inputs = [];
        CallingApp
        Mix_Names
        
        MPA_Beads = []; % Description
        MPA_Bead_Presets % Description
        Database % Description
        fail % Description
        QC_Check % Description
    end
    
    methods (Access = private)
        
        function IndexFileFcn(app, switchcase)
            
            switch (switchcase)
                case {'all','DirectoryFilesListBox'}
                    if isempty(app.DirectoryFilesListBox.Items)==0
                        app.DirectoryFilesListBox.Items=natsort(app.DirectoryFilesListBox.Items);
                        app.DirectoryFilesListBox.ItemsData=1:numel(app.DirectoryFilesListBox.Items);     % update directory file index values
                    else
                    end
                otherwise
            end


        end
        
        
        function DefaultInputs(app)
            
             BeadtemplateImport(app)
%             app.MPA_Bead_Presets = importdata('MPA_Preset_Bead_Populations.mat');
            
%             app.MPA_Bead_Presets.PresetDirectory = fileparts('MPA_Preset_Bead_Populations.mat');
            
%             app.PresetBeadsDropDown.Items = app.MPA_Bead_Presets.Names;
            
        end
        
        function [Data]=App_MPA_CSV_Import_Loop(app, DataX, Bead_Nos)
            
            Samples=app.DataX.Loaded_Samples_Listbox.Filename;
            Controls=app.DataX.Loaded_Samples_Listbox.Sample_Control_Filename;
            
            Sample_L=numel(Samples);
            Odd=1:2:((Sample_L*2)-1);
            Even=2:2:(Sample_L*2);
            
            Filenames(Odd)=Samples;
            Filenames(Even)=Controls; % interleaf so dataset is sample, control, sample, control, etc
            
            Bead_Nos_L=numel(Bead_Nos);
            
            Group_No=length((Filenames));     % Number of groups that are going to be imported
            
            y1=1;       % start of name loop
            x1=1;       % start of bead number loop
            Set_No=1;   % Starting set import number for the loop
            
            for string=1:Group_No
                Name_Body(x1)={char(Filenames{string})};           % gets filename string prefix
                
                for x=1:Bead_Nos_L
                    
                    Name_Ext=strcat((Bead_Nos(x)),'.csv');           % adds bead number and specifies csv file
                    Name_Total=cellstr(strcat(Name_Body(x1), Name_Ext));    % concatenates above strings
                    Name=Name_Total{1};                                     % Conversion of cell to string
                    Nametest(x,y1)={Name};                                % Filenames of all imported Files
                    
                    try  Data.SetA{x,Set_No}=csvread(Name);
                    catch
                    end    % reads constructed string and attempts to import as csv. Ignores error message if file is empty.
                    
                    check=Name_Body{x1};
                    switch(check)
                        case 'blank'
                            Data.SetA{x,Set_No}=[0];
                        otherwise
                            if    isempty(Data.SetA{x,Set_No})==1; Data.SetA{x,Set_No}=[0];
                                
                            elseif    size(Data.SetA{x, Set_No})==0; Data.SetA{x,Set_No}=[0];
                                % Converts empty files to a single 0 in dataset
                            end
                    end
                    
                end
                x1=size(Name_Body,2)+1;
                y1=size(Nametest,2)+1;
                Set_No=size(Data.SetA,2)+1;     % calculates the length of the dataset and adds one for the next file import
            end
            
            
        end
        
        function AbListAssociatedData(app, action, index)
            
            switch (action)
                
                case 'add'
                    index = numel(app.AntibodyMixesListBox.Items)+1;     % number of items in listbox
                    app.AntibodyMixesListBox.Items(index) = (strcat(app.MarkerNameEditField.Value,{' | Mix-'},num2str(app.AbMixNumberEditField.Value))); % create an Ab prefix string
                    
                    IndexFileFcn(app, 'all');                   % index lists
                    
                    app.DataX.AbMixes.SecMarker{index} = char(app.MarkerNameEditField.Value);    % add mix marker in a structure
                    app.DataX.AbMixes.MixName{index} = char(strcat({'Mix-'},num2str(app.AbMixNumberEditField.Value))); % Mix Name
                    app.DataX.AbMixes.ColNum{index} = app.ColumnNumberEditField.Value;   % Column number
                    app.DataX.AbMixes.MixNum{index} = app.AbMixNumberEditField.Value;   % Mix number
                    
                case 'remove'
                    app.DataX.AbMixes.SecMarker(index) = [];   % add mix marker in a structure
                    app.DataX.AbMixes.MixName(index) = [];     % Mix Name
                    app.DataX.AbMixes.ColNum(index) = [];      % Column number
                    app.DataX.AbMixes.MixNum(index) = [];      % Mix number
                    
            end
            
            app.AbMixNumberDropDown.Items = unique(app.DataX.AbMixes.MixName,'stable');   % update dropbox menu with unique mixes
            IndexFileFcn(app, 'all');  % reindex for dropdown menu update
            
        end
        
        function SampleAssociatedData(app,action,index)
            
            switch (action)
                
                case 'add'
                    
                    app.DataX.PreLoaded_Samples_Listbox.R_Group(index) = app.SampleGroupDropDown.Value;      % add group number to structure array
                    app.DataX.PreLoaded_Samples_Listbox.GroupName{index} = app.SampleGroupDropDown.Items(app.SampleGroupDropDown.Value);     % add group name to structure array
                    app.DataX.PreLoaded_Samples_Listbox.Names{index} = strcat(app.SampleNameEditField.Value, {' | '},app.SampleGroupDropDown.Items(app.SampleGroupDropDown.Value));         % sample name
                    app.PreLoadedSampleNamesListBox.Items(index) = app.DirectoryFilesListBox.Items(app.DirectoryFilesListBox.Value);     % sample filename
                    
                    IndexFileFcn(app, 'all');                     % re-index lists
                    
                    app.SampleFilenameDropDown.Items = horzcat(app.PreLoadedSampleNamesListBox.Items, app.PreLoadedControlNamesListBox.Items);
                    
                    IndexFileFcn(app, 'SampleNameDropDown');      % re-index lists
                    
                case 'remove'
                    
                    app.DataX.PreLoaded_Samples_Listbox.R_Group(index) = [];    % add group number to structure array
                    app.DataX.PreLoaded_Samples_Listbox.GroupName{index} = [];  % add group name to structure array
                    app.DataX.PreLoaded_Samples_Listbox.Names{index} =  [];     % sample name
                    app.PreLoadedSampleNamesListBox.Items(index) = [];          % sample filename
                    
                    IndexFileFcn(app, 'all');                       % re-index lists
                    app.SampleFilenameDropDown.Items = horzcat(app.PreLoadedSampleNamesListBox.Items, app.PreLoadedControlNamesListBox.Items);
                    
                    IndexFileFcn(app, 'SampleNameDropDown');        % re-index lists
                    
            end
            
        end
        
        function ControlAssociatedData(app,action, index)
            switch (action)
                
                case 'add'
                    
                    app.DataX.PreLoaded_Control_Listbox.Names{index} =  app.SampleNameEditField.Value;        % add sample name
                    app.PreLoadedControlNamesListBox.Items(index) = app.DirectoryFilesListBox.Items(app.DirectoryFilesListBox.Value);     % add sample filename
                    
                    IndexFileFcn(app, 'all');                      % re-index lists
                    app.SampleFilenameDropDown.Items = horzcat(app.PreLoadedSampleNamesListBox.Items, app.PreLoadedControlNamesListBox.Items);
                    app.SampleControlDropDown.Items = horzcat(app.PreLoadedControlNamesListBox.Items);
                    
                    IndexFileFcn(app, 'SampleNameDropDown');      % re-index lists
                    
                case 'remove'
                    
                    app.DataX.PreLoaded_Control_Listbox.Names{index} =  [];     % remove sample name
                    app.PreLoadedControlNamesListBox.Items(index)  = [];        % remove sample filename
                    
                    IndexFileFcn(app, 'all');                       % re-index lists
                    app.SampleFilenameDropDown.Items = horzcat(app.PreLoadedSampleNamesListBox.Items, app.PreLoadedControlNamesListBox.Items);
                    app.SampleControlDropDown.Items(index) = [];
                    IndexFileFcn(app, 'SampleNameDropDown');        % re-index lists
                    
            end
        end
        
        
        function AllocatedData(app, action, index)
            switch (action)
                
                case 'add'
                    
                    app.LoadedSamplesListBox.Items(index) = app.SampleFilenameDropDown.Items(app.SampleFilenameDropDown.Value);
                    app.DataX.Loaded_Samples_Listbox.Filename{index} = app.LoadedSamplesListBox.Items(index);           % sample filename
                    
                    index2=app.SampleFilenameDropDown.Value;  % sample/control index for concatenated list
                    
                    S_L = numel(app.PreLoadedSampleNamesListBox.Items);
                    C_L = numel(app.PreLoadedControlNamesListBox.Items);
                    
                    if index2>S_L
                        sample_type = 'Control';
                        index2 = (index2-S_L);
                    elseif index2<=S_L
                        sample_type = 'EV Bead';
                        index2 = index2;
                    end
                    
                    switch sample_type
                        case 'EV Bead'
                            
                            app.DataX.Loaded_Samples_Listbox.Sample_Name{index} = string(app.DataX.PreLoaded_Samples_Listbox.Names(index2));                                % sample name
                            app.DataX.Loaded_Samples_Listbox.Sample_Control_Name{index} = char(app.DataX.PreLoaded_Control_Listbox.Names{app.SampleControlDropDown.Value});                     % sample control name
                            app.DataX.Loaded_Samples_Listbox.Sample_Control_Filename{index} = char(app.SampleControlDropDown.Items(app.SampleControlDropDown.Value)); % sample control filename
                            app.DataX.Loaded_Samples_Listbox.Ab_Mix_No(index) = app.AbMixNumberDropDown.Items(app.AbMixNumberDropDown.Value);                   % sample mix number
                            app.DataX.Loaded_Samples_Listbox.Sample_Order = 1:numel(app.DataX.Loaded_Samples_Listbox.Filename);                            % sample order
                            
                            app.DataX.Loaded_Samples_Listbox.R_Group(index) = app.DataX.PreLoaded_Samples_Listbox.R_Group(index2);                              % sample group
                            app.DataX.Loaded_Samples_Listbox.GroupName{index} = char(app.DataX.PreLoaded_Samples_Listbox.GroupName{index2});                          % sample group name
                            
                        case 'Control'
                            
                            app.DataX.Loaded_Samples_Listbox.Sample_Name{index} = char(app.DataX.PreLoaded_Control_Listbox.Names(index2));                               % sample name
                            app.DataX.Loaded_Samples_Listbox.Sample_Control_Name{index} = char(app.DataX.PreLoaded_Control_Listbox.Names{app.SampleControlDropDown.Value});                     % sample control name
                            app.DataX.Loaded_Samples_Listbox.Sample_Control_Filename{index} = char(app.SampleControlDropDown.Items(app.SampleControlDropDown.Value)); % sample control name
                            app.DataX.Loaded_Samples_Listbox.Ab_Mix_No(index) = app.AbMixNumberDropDown.Items(app.AbMixNumberDropDown.Value);                    % sample mix number
                            app.DataX.Loaded_Samples_Listbox.Sample_Order = 1:numel(app.DataX.Loaded_Samples_Listbox.Filename);                            % sample order
                            
                            app.DataX.Loaded_Samples_Listbox.R_Group(index) = 0;              % sample group
                            app.DataX.Loaded_Samples_Listbox.GroupName{index} = 'NA';         % sample group name
                            
                    end
                    
                case 'remove'
                    
                    app.LoadedSamplesListBox.Items(index) = [];
                    app.DataX.Loaded_Samples_Listbox.Filename(index) = [];                      % sample filename
                    app.DataX.Loaded_Samples_Listbox.Sample_Name(index) = [];                   % sample name
                    app.DataX.Loaded_Samples_Listbox.R_Group(index) = [];                       % sample group
                    app.DataX.Loaded_Samples_Listbox.GroupName(index) = [];                     % sample group name
                    app.DataX.Loaded_Samples_Listbox.Sample_Control_Name(index) = [];           % sample control name
                    app.DataX.Loaded_Samples_Listbox.Sample_Control_Filename(index) = [];       % sample control filename
                    app.DataX.Loaded_Samples_Listbox.Ab_Mix_No(index) = [];                     % sample mix number
                    app.DataX.Loaded_Samples_Listbox.Sample_Order = 1:numel(app.DataX.Loaded_Samples_Listbox.Filename);  % sample order
                    
            end
            
            IndexFileFcn(app, 'all');        % re-index lists
            
        end
        
        function ClearWorkspace(app)
            
            app.DataX = [];
            
            app.DirectoryFilesListBox.Items(:,:) = [];
            app.DirectoryFilesListBox.ItemsData(:,:) = [];
            app.DirectoryFilesListBox.Value(:,:) = [];
            
            
            DefaultInputs(app)
            
        end
        
        function MPA_Bead_Table_Construct(app)
            
            app.MPA_Beads.Nos = app.BeadIdentifierListBox.Items(2:end);
            app.MPA_Beads.Labels = app.BeadMarkerListBox.Items(2:end);
            app.MPA_Beads.Isotype = app.BeadIsotypeListBox.Items(2:end);
            app.MPA_Beads.Table = table(app.MPA_Beads.Nos,app.MPA_Beads.Labels,app.MPA_Beads.Isotype);
            
            app.MPA_Beads.Nos_L=size(app.MPA_Beads.Nos,1);                      % calculates the number of beads (will be 39 for just standard MACSPlex set)
            
            app.BeadIdentifierListBox.Items = vertcat({'All'},(string(num2cell(app.MPA_Beads.Nos(:)))));
            app.BeadMarkerListBox.Items = vertcat({'All'},(app.MPA_Beads.Labels(:)));
            app.BeadIsotypeListBox.Items = vertcat({'All'}, (app.MPA_Beads.Isotype(:)));
            
        end
        
        function UpdateStatusWindow(app, string)
            
            i=length(app.CommandWindowListBox.Items)+1;
            app.CommandWindowListBox.Items(i)={[datestr(datetime('now')),string]};
            pause(0.1)
            scroll(app.CommandWindowListBox,'bottom');
        end
        
        function results = BeadSelected(app, index)
            app.BeadIdentifierListBox.Value = index;
            app.BeadIsotypeListBox.Value = index;
            app.BeadMarkerListBox.Value = index;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.DataX.PreLoaded_Control_Listbox.Names=[];
            app.DataX.PreLoaded_Samples_Listbox.Names=[];
            app.DataX.AbMixes.Secondaries{1}=[];
            app.QC_Check = 0;
            
            DefaultInputs(app)
            PresetBeadsDropDownValueChanged(app)
            
            app.CallingApp=mainapp;
        end

        % Callback function
        function AbMixAddButtonPushed(app, event)
            if isempty(app.MarkerNameEditField.Value)==1    % If marker name field is empty
                UpdateStatusWindow(app, ' | Error: input Ab mix ''Marker name'' ');
                
            else                                            % If marker name field is not empty
                
                l = length(app.AntibodyMixesListBox.Items); % number of items in listbox
                AbListAssociatedData(app, 'add', l+1)       % add associated ab mix information
                app.MarkerNameEditField.Value = '';         % clear the marker name field for next input
                
            end
        end

        % Callback function
        function AbMixRemoveButtonPushed(app, event)
            
            if isempty(app.AntibodyMixesListBox.Value) == 0
                i=app.AntibodyMixesListBox.Value;       % index of value to be removed;
                app.AntibodyMixesListBox.Items(i)=[];   % remove item
                IndexFileFcn(app,'all');                % index lists
                AbListAssociatedData(app, 'remove', i)  % remove associated ab mix information
                
            else
            end
            
        end

        % Value changed function: DirectoryFilesListBox
        function DirectoryFilesListBoxValueChanged(app, event)
            DefaultInputs(app)
        end

        % Callback function
        function LoadFileButtonPushed(app, event)
            
            index=app.SampleOrderEditField.Value;
            
            if isempty(app.SampleFilenameDropDown.Value)==1    % If marker name field is empty
                UpdateStatusWindow(app, ' | Error: No sample information provided to load samples');
                return
            else
            end
            
            AllocatedData(app, 'add', index)
            IndexFileFcn(app, 'all')
            app.SampleOrderEditField.Value = numel(app.LoadedSamplesListBox.Items)+1;
            
        end

        % Callback function
        function RemoveFileButtonPushed(app, event)
            if isempty(app.LoadedSamplesListBox.Items)==0
                if isempty(app.LoadedSamplesListBox.Value)==1    % If marker name field is empty
                    UpdateStatusWindow(app, ' | Error: Select loaded sample to remove ');
                    return
                else
                end
                
                AllocatedData(app, 'remove', app.LoadedSamplesListBox.Value)
                
                IndexFileFcn(app, 'all')
                
                app.SampleOrderEditField.Value = numel(app.LoadedSamplesListBox.Items)+1;
                
            else
                UpdateStatusWindow(app, ' | Error: No loaded samples to remove ');
                
            end
        end

        % Callback function: NewDirectoryMenu, SelectCSVDirectoryButton
        function NewDirectoryMenuSelected(app, event)
            %             try
            
            ClearWorkspace(app)
            
            app.FileImportUIFigure.Visible = 'off';
            app.DataX.AppVars.Dir = uigetdir; % select directory
            app.FileImportUIFigure.Visible = 'on';
            
            cd(app.DataX.AppVars.Dir) % change to new directory folder
            
            dinfo = dir('*.csv');   % look for all csv files in directory
            
            Names=struct2cell(dinfo);   % convert file info to cell array
            Names2=natsort(Names(1,1:end));      % extract file names
            
            for i=1:numel(Names2)
                Names3(i)={Names2{i}(1:end-6)};  % remove last 6 characters of str ('xx.csv')
            end
            
            try
                app.DirectoryFilesListBox.Items=unique(Names3,'stable');  % find the unique file names when bead population number is removed
            catch
                UpdateStatusWindow(app, ' | Error loading files from directory. Please check directory folder contains .csv/.xlsx files');
            end
            
            IndexFileFcn(app,'all')
            UpdateStatusWindow(app, ' | Directory Selected')
            %             catch
            
            %                 return
            %             end
        end

        % Callback function
        function SampleTypeButtonGroupSelectionChanged(app, event)
            switch app.SampleTypeButtonGroup.SelectedObject.Text
                case 'Bead Control'
                    app.SampleControlDropDown.Enable = 'off';
                    app.SampleNameEditField.Value = 'Control';
                    app.GroupNumberEditField.Value = 0;
                    
                case 'EV Sample'
                    app.SampleControlDropDown.Enable='on';
                    app.SampleNameEditField.Value='Sample';
                    app.GroupNumberEditField.Value = 1;
            end
        end

        % Callback function
        function FileImportUIFigureCloseRequest(app, event)
            app.test = app.GroupNameEditField_3.Value;
            delete(app)
            
        end

        % Callback function
        function SaveMenuSelected(app, event)
            
            % assign lists and drop down data to global structure array
            
            Database=app.Database;
            try
                uisave({'Database'},[datestr(datetime('now')),' Experiment workspace'])
            catch
                UpdateStatusWindow(app, ' | Error: File save cancelled');
                return
            end
            
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            delete(app)
        end

        % Callback function
        function ImportWorkspaceMenuSelected(app, event)
            
            ClearWorkspace(app)         % clear all variables
            
            
            [filename, foldername] = uigetfile('*.mat', 'Select a mat file');  % get directory and import file
            fullname = fullfile(foldername, filename);  % get directory path
            Data = load(fullname);  % load workspace file
            app.DataX=Data.DataX;   % extract data to global structure array variable
            
            try
                cd(app.DataX.AppVars.Dir) % change to new directory folder
                app.DirectoryEditField.Value = app.DataX.AppVars.Dir;
            catch
                UpdateStatusWindow(app, ' | Error: Sample directory does not exist, please update');
            end
            
            % Add sample filenames to list
            app.DirectoryFilesListBox.Items = app.DataX.AppVars.DirectoryFilesListBox.Items;
            app.DirectoryFilesListBox.ItemsData = app.DataX.AppVars.DirectoryFilesListBox.ItemsData;
            app.DirectoryFilesListBox.Value = app.DataX.AppVars.DirectoryFilesListBox.Value;
            
            % Add antibody mixes to list
            app.AntibodyMixesListBox.Items = app.DataX.AppVars.AntibodyMixesListBox.Items;
            app.AntibodyMixesListBox.ItemsData = app.DataX.AppVars.AntibodyMixesListBox.ItemsData;
            app.AntibodyMixesListBox.Value = app.DataX.AppVars.AntibodyMixesListBox.Value;
            
            % Add loaded samples to list
            app.PreLoadedSampleNamesListBox.Items = app.DataX.AppVars.PreLoadedSampleNamesListBox.Items;
            app.PreLoadedSampleNamesListBox.ItemsData = app.DataX.AppVars.PreLoadedSampleNamesListBox.ItemsData;
            app.PreLoadedSampleNamesListBox.Value = app.DataX.AppVars.PreLoadedSampleNamesListBox.Value;
            
            % Add loaded controls to list
            app.PreLoadedControlNamesListBox.Items = app.DataX.AppVars.PreLoadedControlNamesListBox.Items;
            app.PreLoadedControlNamesListBox.ItemsData = app.DataX.AppVars.PreLoadedControlNamesListBox.ItemsData;
            app.PreLoadedControlNamesListBox.Value = app.DataX.AppVars.PreLoadedControlNamesListBox.Value;
            
            % Add samples to dropdown list
            app.SampleFilenameDropDown.Items = app.DataX.AppVars.SampleNameDropDown.Items;
            app.SampleFilenameDropDown.ItemsData = app.DataX.AppVars.SampleNameDropDown.ItemsData;
            app.SampleFilenameDropDown.Value = app.DataX.AppVars.SampleNameDropDown.Value;
            
            % Add sample controls to dropdown list
            app.SampleControlDropDown.Items = app.DataX.AppVars.SampleControlDropDown.Items;
            app.SampleControlDropDown.ItemsData = app.DataX.AppVars.SampleControlDropDown.ItemsData;
            app.SampleControlDropDown.Value = app.DataX.AppVars.SampleControlDropDown.Value;
            
            % Add antibody mixes to dropdown list
            app.AbMixNumberDropDown.Items = app.DataX.AppVars.AbMixNumberDropDown.Items;
            app.AbMixNumberDropDown.ItemsData = app.DataX.AppVars.AbMixNumberDropDown.ItemsData;
            app.AbMixNumberDropDown.Value = app.DataX.AppVars.AbMixNumberDropDown.Value;
            
            % Add loaded samples to list
            app.LoadedSamplesListBox.Items = app.DataX.AppVars.LoadedSamplesListBox.Items;
            app.LoadedSamplesListBox.ItemsData = app.DataX.AppVars.LoadedSamplesListBox.ItemsData;
            app.LoadedSamplesListBox.Value = app.DataX.AppVars.LoadedSamplesListBox.Value;
            
        end

        % Callback function
        function RemoveControlButtonValueChanged(app, event)
            if isempty(app.LoadedControlsListBox.Items)==0
                
                app.LoadedControlsListBox.Items(numel(app.LoadedControlsListBox.Items)+1)=app.LoadedControlsListBox.Items(app.LoadedControlsListBox.Value); % add name from loaded to unloaded
                app.LoadedControlsListBox.Items=sort(app.LoadedControlsListBox.Items);
                app.LoadedControlsListBox.Items(app.LoadedControlsListBox.Value)=[]; % remove loaded name
                IndexFileFcn(app, 'all')
                
            else
            end
        end

        % Callback function
        function LoadedSamplesListBoxValueChanged(app, event)

            index=app.LoadedSamplesListBox.Value;
            app.SampleNameEditField_2.Value = char(app.DataX.Loaded_Samples_Listbox.Sample_Name{index});  % Sample Name
            app.GroupNameEditField_2.Value = char(app.DataX.Loaded_Samples_Listbox.GroupName{index});     % Sample Groupname
            app.AbMixEditField.Value = char(app.DataX.Loaded_Samples_Listbox.Ab_Mix_No(index));           % Ab Mix number
            app.ControlNameEditField.Value = char(app.DataX.Loaded_Samples_Listbox.Sample_Control_Name{index}); % Control Name
            app.SampleOrderEditField_2.Value = app.DataX.Loaded_Samples_Listbox.Sample_Order(index);      % Sample order
            
        end

        % Button pushed function: ImportDataButton
        function Construct_DatasetSelected(app, event)
            if app.fail == 1
                UpdateStatusWindow(app, ' | Error: Dataset must pass QC before it can be imported');
                
            elseif app.QC_Check == 0
                UpdateStatusWindow(app, ' | Error: Dataset must pass QC before it can be imported');
                
            else
                cd(string(app.Database.General.CSV_Directory))
                
                [app.Database] = MPA_CSV_Import_Loop(app.Database);   % Import CSV files
                
                SaveMenuSelected(app, event)    % save workspace and files
                
                SendDataToMainApp(app.CallingApp, app.Database)  % send variables back to main app
                
                CloseApp(app)   % close application window
            end
        end

        % Close request function: FileImportUIFigure
        function CloseApp(app, event)
            
            delete(app)
            
        end

        % Callback function
        function SampleAddButtonPushed(app, event)
            
            
            %% Check for errors before allowing of loading
            error = 0;
            
            if isempty(app.SampleNameEditField.Value) == 1  % check if sample name is inputted
                UpdateStatusWindow(app, ' | Error: input sample name ');
                error = 1;
            else
            end
            
            if isempty(app.DirectoryFilesListBox.Value) == 1  % check if sample is selected
                UpdateStatusWindow(app, ' | Error: Select file for loading');
                error = 1;
            else
                
            end
            
            if error == 1  % do not allow function to continue if error found
                return
            else
            end
            
            switch app.SampleTypeButtonGroup.SelectedObject.Text
                
                case 'Control'
                    if strcmp(app.SampleNameEditField.Value, app.DataX.PreLoaded_Control_Listbox.Names) == 1
                        UpdateStatusWindow(app, ' | Error: Control name must be unique, please rename ');
                        
                    else
                        
                        index = length(app.PreLoadedControlNamesListBox.Items)+1;
                        
                        ControlAssociatedData(app,'add', index)
                        
                        app.DirectoryFilesListBox.Items(app.DirectoryFilesListBox.Value) = '';    % remove filename from list
                        
                        IndexFileFcn(app, 'all');      % re-index lists
                        
                    end
                    
                case 'EV Bead'
                    string = strcat(app.SampleNameEditField.Value,{' | '}, app.SampleGroupDropDown.Items(app.SampleGroupDropDown.Value));
                    
                    if strcmp(string, app.DataX.PreLoaded_Samples_Listbox.Names) == 1 % check sample name is unique
                        UpdateStatusWindow(app, ' | Error: Sample name must be unique, please rename ');
                        
                    else
                        index = length(app.PreLoadedSampleNamesListBox.Items)+1;     % retrieve index for pre-loaded sample list
                        
                        SampleAssociatedData(app,'add',index)   % add sample data to lists
                        
                        app.DirectoryFilesListBox.Items(app.DirectoryFilesListBox.Value) = '';    % remove filename from list
                        
                        IndexFileFcn(app, 'all');      % re-index lists
                        
                    end
            end
            
            %% reset to default input box value for next sample
            DefaultInputs(app)
            
        end

        % Callback function
        function SampleTypeButtonGroupSelectionChanged2(app, event)
            
            switch app.SampleTypeButtonGroup.SelectedObject.Text
                
                case 'Control'
                    
                    app.SampleNameEditField.Value = ['Control ' num2str(numel(app.PreLoadedControlNamesListBox.Items)+1)];
                    app.SampleGroupDropDown.Enable = 'off';
                    
                case 'EV Bead'
                    
                    app.SampleNameEditField.Value = ['Sample ' num2str(numel(app.PreLoadedSampleNamesListBox.Items)+1)];
                    app.SampleGroupDropDown.Enable = 'on';
                    
            end
            
        end

        % Callback function
        function SampleRemoveButtonPushed(app, event)
            switch app.SampleTypeButtonGroup.SelectedObject.Text % control or sample being removed
                
                case 'Control'
                    
                    if isempty(app.PreLoadedControlNamesListBox.Items) == 0     % check item can be removed
                        index = (app.PreLoadedControlNamesListBox.Value);       % index of sample being removed
                        
                        app.DirectoryFilesListBox.Items(numel(app.DirectoryFilesListBox.Items)+1)=app.PreLoadedControlNamesListBox.Items(app.PreLoadedControlNamesListBox.Value);
                        
                        ControlAssociatedData(app,'remove', index)              % add sample data to lists
                    else
                    end
                    
                case 'EV Bead'
                    
                    if isempty(app.PreLoadedSampleNamesListBox.Items) == 0      % check item can be removed
                        index = (app.PreLoadedSampleNamesListBox.Value);        % index of sample being removed
                        
                        app.DirectoryFilesListBox.Items(numel(app.DirectoryFilesListBox.Items)+1)=app.PreLoadedSampleNamesListBox.Items(app.PreLoadedSampleNamesListBox.Value);
                        
                        SampleAssociatedData(app,'remove',index)                % add sample data to lists
                    else
                    end
            end
        end

        % Callback function
        function PreLoadedSampleNamesListBoxValueChanged(app, event)
            index = app.PreLoadedSampleNamesListBox.Value;
            
            app.SampleNameEditField.Value = string(app.DataX.PreLoaded_Samples_Listbox.Names{index});
            
            
        end

        % Callback function
        function PreLoadedControlNamesListBoxValueChanged(app, event)
            index = app.PreLoadedControlNamesListBox.Value;
            
            app.SampleNameEditField.Value = app.DataX.PreLoaded_Control_Listbox.Names{index};
            
        end

        % Callback function
        function UpdateWorkspaceDirectoryMenuSelected(app, event)
            
            selpath = uigetdir;  % get directory and import file
            app.DataX.AppVars.Dir = selpath;  % get directory path
            
            try
                cd(app.DataX.AppVars.Dir) % change to new directory folder
                UpdateStatusWindow(app, ' | Workspace directory updated');
            catch
                UpdateStatusWindow(app, ' | Error: Unable update directory ');
                return
            end
            
        end

        % Button pushed function: AddPopulationButton
        function AddPopulationButtonPushed(app, event)
            error = 0;
            if isempty(app.MarkerEditField.Value) == 1
                UpdateStatusWindow(app, ' | Error: Input bead marker name');
                error = 1;
            else
            end
            
            if isempty(app.IdentifierEditField.Value) == 1
                UpdateStatusWindow(app, ' | Error: Input bead identifier');
                error = 1;
            elseif  contains(num2str(app.IdentifierEditField.Value),app.BeadIdentifierListBox.Items)  == 1
                UpdateStatusWindow(app, ' | Error: Bead identifier is not unique');
                error = 1;
            end
            
            if isempty(app.IsotypeEditField.Value) == 1
                UpdateStatusWindow(app, ' | Error: Input bead isotype name');
                error = 1;
            else
            end
            
            if error == 1
                return
            else
            end
            
            i = numel(app.BeadIdentifierListBox.Items) + 1;
            
            app.BeadIdentifierListBox.Items = horzcat(app.BeadIdentifierListBox.Items, num2str(app.IdentifierEditField.Value));
            app.BeadIdentifierListBox.ItemsData = 1:numel(app.BeadIdentifierListBox.Items);
            
            app.BeadMarkerListBox.Items = horzcat(app.BeadMarkerListBox.Items,app.MarkerEditField.Value);
            app.BeadMarkerListBox.ItemsData = 1:numel(app.BeadMarkerListBox.Items);
            
            app.BeadIsotypeListBox.Items = horzcat(app.BeadIsotypeListBox.Items,app.IsotypeEditField.Value);
            app.BeadIsotypeListBox.ItemsData = 1:numel(app.BeadIsotypeListBox.Items);
            
            MPA_Bead_Table_Construct(app)
            
        end

        % Button pushed function: RemovePopulationButton
        function RemovePopulationButtonPushed(app, event)
            i = app.BeadIdentifierListBox.Value;
            
            app.BeadIdentifierListBox.Items(i) = [];
            app.BeadIdentifierListBox.ItemsData = 1:numel(app.BeadIdentifierListBox.Items);
            
            app.BeadMarkerListBox.Items(i) = [];
            app.BeadMarkerListBox.ItemsData = 1:numel(app.BeadMarkerListBox.Items);
            
            app.BeadIsotypeListBox.Items(i) = [];
            app.BeadIsotypeListBox.ItemsData = 1:numel(app.BeadIsotypeListBox.Items);
            
            MPA_Bead_Table_Construct(app)
        end

        % Value changed function: PresetBeadsDropDown
        function PresetBeadsDropDownValueChanged(app, event)
            
%             Bead_Import_String = char(genvarname(app.PresetBeadsDropDown.Value));
%             
%             if contains(Bead_Import_String, 'Blank') == 1
%                 app.BeadIdentifierListBox.Items = {'All'};
%                 app.BeadMarkerListBox.Items = {'All'};
%                 app.BeadIsotypeListBox.Items = {'All'};
%             else
%                 
%                 MPA_Beads.Info=app.MPA_Bead_Presets.(Bead_Import_String);   % imports bead data from xlsx file
%                 MPA_Beads.Nos=flipud(table2array(MPA_Beads.Info(:,1)));     % imports bead numbers
%                 MPA_Beads.Labels=flipud(table2cell(MPA_Beads.Info(:,3)));   % imports bead names to respective bead numbers
%                 MPA_Beads.Isotype=flipud(table2cell(MPA_Beads.Info(:,2)));  % imports bead isotypes to respective bead numbers
%                 MPA_Beads.Nos_L=size(MPA_Beads.Nos,1);                      % calculates the number of beads (will be 39 for just standard MACSPlex set)
%                 
%                 ListboxLength = numel(app.BeadIdentifierListBox.Items);
%                 app.BeadIdentifierListBox.Items = vertcat({'All'},(string(num2cell(MPA_Beads.Nos))));
%                 ListboxLength = 1:numel(app.BeadIdentifierListBox.Items);
%                 app.BeadIdentifierListBox.ItemsData = 1:numel(ListboxLength);
%                 app.BeadMarkerListBox.Items = vertcat({'All'},(MPA_Beads.Labels));
%                 app.BeadMarkerListBox.ItemsData = 1:numel(ListboxLength);
%                 app.BeadIsotypeListBox.Items = vertcat({'All'}, (MPA_Beads.Isotype));
%                 app.BeadIsotypeListBox.ItemsData = 1:numel(ListboxLength);
%                 
%             end
        end

        % Button pushed function: SavePresetButton
        function SavePresetButtonPushed(app, event)
            
            Preset_Name = inputdlg('Enter Preset Name','Save Preset',[1 35], {'MPA Beads'}); % obtain the user's desired name for preset
            app.MPA_Bead_Presets.Names(numel(app.MPA_Bead_Presets.Names)+1) = Preset_Name; % save the preset name
            app.MPA_Bead_Presets.(char(genvarname(Preset_Name))) = app.MPA_Beads.Table; % save constructed table as the preset database
            
            Filepath = app.MPA_Bead_Presets.PresetDirectory;   % Filepath presets
            Fullname = char([Filepath,'/','Preset_Bead_Populations','.mat']); % full filepath and filename for saving preset bead name database
            
            Preset = app.MPA_Bead_Presets;
            save('MPA_Preset_Bead_Populations.mat', 'Preset')  % save preset bead name database
            
            app.PresetBeadsDropDown.Items = app.MPA_Bead_Presets.Names;
            
        end

        % Menu selected function: ClearStatusWindowMenu
        function ClearStatusWindowMenuSelected(app, event)
            
        end

        % Button pushed function: RemovePresetButton
        function RemovePresetButtonPushed(app, event)
            Bead_Import_String = char(app.PresetBeadsDropDown.Value);
            
            if contains(Bead_Import_String, 'Blank') == 1
                UpdateStatusWindow(app, ' | Error: Unable to remove ''Blank'' preset');
                return
            elseif contains(Bead_Import_String, 'MACSPlex Human EV') == 1
                UpdateStatusWindow(app, ' | Error: Unable to remove ''MACSPlex Human EV'' preset');
                return
            end
            
            app.MPA_Bead_Presets.Names(find(contains(app.MPA_Bead_Presets.Names,app.PresetBeadsDropDown.Value)))
            
            Preset_Delete_String = char(genvarname(app.PresetBeadsDropDown.Value));                                     % convert name to variable name
            app.MPA_Bead_Presets.Names(find(contains(app.MPA_Bead_Presets.Names,app.PresetBeadsDropDown.Value))) = '';  % remove name from directory
            app.MPA_Bead_Presets = rmfield(app.MPA_Bead_Presets,Preset_Delete_String);                                  % remove associated data from directory
            
            Preset = app.MPA_Bead_Presets;
            save('Preset_Bead_Populations.mat', 'Preset')  % save preset bead name database
            
            app.BeadIdentifierListBox.Items = {'All'};
            app.BeadMarkerListBox.Items = {'All'};
            app.BeadIsotypeListBox.Items = {'All'};
            app.PresetBeadsDropDown.Items = app.MPA_Bead_Presets.Names;
            
            
        end

        % Callback function
        function AddGroupButtonPushed(app, event)
            
            if contains(app.SampleGroupsListBox.Items,app.GroupNameEditField_3.Value) == 1 % need to fix
                UpdateStatusWindow(app, ' | Error: Group name must be unique');
                return
            else
                string(app.SampleGroupsListBox.Items)
                i=numel(app.SampleGroupsListBox.Items)+1;
                app.SampleGroupsListBox.Items{i} = app.GroupNameEditField_3.Value;
                app.SampleGroupsListBox.ItemsData = 1:numel(app.SampleGroupsListBox.Items);
                app.SampleGroupDropDown.Items = app.SampleGroupsListBox.Items;
                app.SampleGroupDropDown.ItemsData = 1:numel(app.SampleGroupDropDown.Items);
            end
            
        end

        % Callback function
        function RemoveGroupButtonPushed(app, event)
            
            i = app.SampleGroupsListBox.Value;
            app.SampleGroupsListBox.Items(i) = [];
            app.SampleGroupsListBox.ItemsData = 1:numel(app.SampleGroupsListBox.Items);
            app.SampleGroupDropDown.Items = app.SampleGroupsListBox.Items;
            app.SampleGroupDropDown.ItemsData = 1:numel(app.SampleGroupDropDown.Items);
            
        end

        % Callback function
        function SampleFilenameDropDownValueChanged(app, event)
            
            index = app.SampleFilenameDropDown.Value;
            S_max = numel(app.PreLoadedSampleNamesListBox.Items);
            
            if index> S_max
                index = index - S_max;
                app.DataX.PreLoaded_Control_Listbox.Names{index};
                app.SampleNameEditField_2.Value = (app.DataX.PreLoaded_Control_Listbox.Names{index});
                
            else
                app.SampleNameEditField_2.Value = char(app.DataX.PreLoaded_Samples_Listbox.Names{index});
            end
            
        end

        % Value changed function: BeadIdentifierListBox
        function BeadIdentifierListBoxValueChanged(app, event)
            BeadSelected(app, app.BeadIdentifierListBox.Value);
        end

        % Value changed function: BeadMarkerListBox
        function BeadMarkerListBoxValueChanged(app, event)
            BeadSelected(app, app.BeadMarkerListBox.Value);
        end

        % Value changed function: BeadIsotypeListBox
        function BeadIsotypeListBoxValueChanged(app, event)
            BeadSelected(app, app.BeadIsotypeListBox.Value);
        end

        % Button pushed function: ConstuctDatabaseButton
        function CreateDatabaseButtonPushed(app, event)
            if numel(app.BeadIdentifierListBox.Items) == 1
                UpdateStatusWindow(app, ' | Error: Capture bead information must be added before creating database template');
                return
            elseif numel(app.DirectoryFilesListBox.Items) == 0
                UpdateStatusWindow(app, ' | Error: A file directory must be selected before creating database template');
                return
            else
                
                SampleDirectoryFiles = app.DirectoryFilesListBox.Items;
                MPA_Bead_Table_Construct(app)
                
                [Sample, BeadAssay, StatusLabel, General, Control] =  MPA_Database_Construction(SampleDirectoryFiles, app.MPA_Beads, app.DataX.AppVars.Dir);
                
                Data = cell2table(Sample.Data); % Create table for sample info
                Data.Properties.VariableNames = (Sample.Header);
                
                Data2 = cell2table(BeadAssay.Data); % Create table for assay info
                Data2.Properties.VariableNames = (BeadAssay.Header);
                
                Data3 = cell2table(StatusLabel.Data); % Create table for labelling info
                Data3.Properties.VariableNames = (StatusLabel.Header);
                
                Data4 = cell2table(General.Data); % Create table for labelling info
                Data4.Properties.VariableNames = (General.Header);
                
                Data5 = cell2table(Control.Data); % Create table for labelling info
                Data5.Properties.VariableNames = (Control.Header);
                
                [file,path] = uiputfile('*.xlsx');
                
                writetable(Data, [path, file], 'Sheet','Sample')
                writetable(Data5, [path, file], 'Sheet','Controls')
                writetable(Data3, [path, file], 'Sheet','Labelling')
                writetable(Data2, [path, file], 'Sheet','Beads')
                writetable(Data4, [path, file], 'Sheet','General')
                
                UpdateStatusWindow(app, ' | Database template created successfully')
            end
            
        end

        % Button pushed function: ImportDatabaseButton
        function ImportDatabaseButtonPushed(app, event)
            app.Database = [];
            
            %             try
            app.FileImportUIFigure.Visible ='off';
            [file,path] = uigetfile('*.xlsx');
            app.FileImportUIFigure.Visible ='on';
            Filename = [path, file];
            
            SheetStr = {'Sample', 'Beads', 'Labelling', 'General','Controls'};
            [~,B] = xlsfinfo(Filename);
            
            error = 0;
            for ii = 1:numel(SheetStr)
                sheetValid = any(strcmp(B, SheetStr{ii}));
                pres = sum(sheetValid);
                if pres == 1
                else
                    UpdateStatusWindow(app, [' | Error: The following worksheet was not found: ', SheetStr{ii}]);
                    error = 1;
                end
            end
            
            if error == 0
                app.Database.Sample = readtable(Filename,'Sheet','Sample');
                
                T = app.Database.Sample.Sample_Set_ID;
                class(T)
                
                if isa(T,'double') == 1
                    Array = app.Database.Sample.Sample_Set_ID;
                    app.Database.Sample.Sample_Set_ID = cellstr(num2str(app.Database.Sample.Sample_Set_ID));
                elseif isa(T,'cell') == 1
                    Array = cellfun(@str2num, app.Database.Sample.Sample_Set_ID); % convert Sample Set IDs to numbers
                end
                
                [~, I] = sort(Array);                                         % Sort listed sample set IDs by value
                app.Database.Sample = app.Database.Sample(I, :);              % Sort Sample Info array by sample set ID
                app.Database.Beads = readtable(Filename,'Sheet','Beads');
                app.Database.Labelling = readtable(Filename,'Sheet','Labelling');
                app.Database.General = readtable(Filename,'Sheet','General');
                app.Database.Controls = readtable(Filename,'Sheet','Controls');
                
                UpdateStatusWindow(app, ' | Database import successful')
                app.QC_Check = 0;
            else
            end
            
            %             catch
            %                 UpdateStatusWindow(app, ' | Database import unsuccessful')
            %             end
            
        end

        % Button pushed function: DatabaseQCButton
        function DatabaseQCButtonPushed(app, event)
            UpdateStatusWindow(app, ' | Database QC Initiated...')
            
            [msg, app.fail] = MPA_Database_QC(app.Database);
            
            for i = 1:numel(msg)
                UpdateStatusWindow(app, msg{i});
            end
            
            if app.fail == 0
                app.QC_Check = 1;
            else
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FileImportUIFigure = uifigure('Visible', 'off');
            app.FileImportUIFigure.Name = 'File Import';
            app.FileImportUIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseApp, true);
            app.FileMenu = uimenu(app.FileImportUIFigure);
            app.FileMenu.Text = 'File';
            app.NewDirectoryMenu = uimenu(app.FileMenu);
            app.NewDirectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @NewDirectoryMenuSelected, true);
            app.NewDirectoryMenu.Accelerator = 'N';
            app.NewDirectoryMenu.Text = 'New Directory';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Accelerator = 'Q';
            app.QuitMenu.Text = 'Quit';
            app.EditMenu = uimenu(app.FileImportUIFigure);
            app.EditMenu.Text = 'Edit';
            app.ClearStatusWindowMenu = uimenu(app.EditMenu);
            app.ClearStatusWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearStatusWindowMenuSelected, true);
            app.ClearStatusWindowMenu.Text = 'Clear Status Window';
            app.DirectoryFilesListBoxLabel = uilabel(app.FileImportUIFigure);
            app.DirectoryFilesListBoxLabel.Text = 'Directory Files';
            app.DirectoryFilesListBox = uilistbox(app.FileImportUIFigure);
            app.DirectoryFilesListBox.Items = {};
            app.DirectoryFilesListBox.ValueChangedFcn = createCallbackFcn(app, @DirectoryFilesListBoxValueChanged, true);
            app.DirectoryFilesListBox.Value = {};
            app.TabGroup = uitabgroup(app.FileImportUIFigure);
            app.BeadInfoTab = uitab(app.TabGroup);
            app.BeadInfoTab.Title = 'Bead Info';
            app.BeadIdentifierListBox = uilistbox(app.BeadInfoTab);
            app.BeadIdentifierListBox.Items = {'All'};
            app.BeadIdentifierListBox.ValueChangedFcn = createCallbackFcn(app, @BeadIdentifierListBoxValueChanged, true);
            app.BeadIdentifierListBox.Value = 'All';
            app.MarkerEditFieldLabel = uilabel(app.BeadInfoTab);
            app.MarkerEditFieldLabel.Text = 'Marker';
            app.MarkerEditField = uieditfield(app.BeadInfoTab, 'text');
            app.IdentifierEditFieldLabel = uilabel(app.BeadInfoTab);
            app.IdentifierEditFieldLabel.Text = 'Identifier';
            app.IdentifierEditField = uieditfield(app.BeadInfoTab, 'numeric');
            app.IdentifierEditField.Limits = [10 99];
            app.IdentifierEditField.Value = 10;
            app.IsotypeEditFieldLabel = uilabel(app.BeadInfoTab);
            app.IsotypeEditFieldLabel.Text = 'Isotype';
            app.IsotypeEditField = uieditfield(app.BeadInfoTab, 'text');
            app.AddPopulationButton = uibutton(app.BeadInfoTab, 'push');
            app.AddPopulationButton.ButtonPushedFcn = createCallbackFcn(app, @AddPopulationButtonPushed, true);
            app.AddPopulationButton.Text = 'Add Population';
            app.BeadMarkerListBox = uilistbox(app.BeadInfoTab);
            app.BeadMarkerListBox.Items = {'All'};
            app.BeadMarkerListBox.ValueChangedFcn = createCallbackFcn(app, @BeadMarkerListBoxValueChanged, true);
            app.BeadMarkerListBox.Value = 'All';
            app.BeadIsotypeListBox = uilistbox(app.BeadInfoTab);
            app.BeadIsotypeListBox.Items = {'All'};
            app.BeadIsotypeListBox.ValueChangedFcn = createCallbackFcn(app, @BeadIsotypeListBoxValueChanged, true);
            app.BeadIsotypeListBox.Value = 'All';
            app.PresetBeadsDropDown = uidropdown(app.BeadInfoTab);
            app.PresetBeadsDropDown.Items = {};
            app.PresetBeadsDropDown.ValueChangedFcn = createCallbackFcn(app, @PresetBeadsDropDownValueChanged, true);
            app.PresetBeadsDropDown.Value = {};
            app.SavePresetButton = uibutton(app.BeadInfoTab, 'push');
            app.SavePresetButton.ButtonPushedFcn = createCallbackFcn(app, @SavePresetButtonPushed, true);
            app.SavePresetButton.Text = 'Save Preset';
            app.RemovePopulationButton = uibutton(app.BeadInfoTab, 'push');
            app.RemovePopulationButton.ButtonPushedFcn = createCallbackFcn(app, @RemovePopulationButtonPushed, true);
            app.RemovePopulationButton.Text = 'Remove Population';
            app.RemovePresetButton = uibutton(app.BeadInfoTab, 'push');
            app.RemovePresetButton.ButtonPushedFcn = createCallbackFcn(app, @RemovePresetButtonPushed, true);
            app.RemovePresetButton.Text = 'Remove Preset';
            app.CommandWindowListBox = uilistbox(app.FileImportUIFigure);
            app.CommandWindowListBox.Items = {};
            app.CommandWindowListBox.Value = {};
            app.SelectCSVDirectoryButton = uibutton(app.FileImportUIFigure, 'push');
            app.SelectCSVDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @NewDirectoryMenuSelected, true);
            app.SelectCSVDirectoryButton.Text = '1. Select CSV Directory';
            app.ConstuctDatabaseButton = uibutton(app.FileImportUIFigure, 'push');
            app.ConstuctDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @CreateDatabaseButtonPushed, true);
            app.ConstuctDatabaseButton.Text = '2. Constuct Database';
            app.DatabaseQCButton = uibutton(app.FileImportUIFigure, 'push');
            app.DatabaseQCButton.ButtonPushedFcn = createCallbackFcn(app, @DatabaseQCButtonPushed, true);
            app.DatabaseQCButton.Text = '4. Database QC';
            app.ImportDataButton = uibutton(app.FileImportUIFigure, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @Construct_DatasetSelected, true);
            app.ImportDataButton.Text = '5. Import Data';
            app.ImportDatabaseButton = uibutton(app.FileImportUIFigure, 'push');
            app.ImportDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDatabaseButtonPushed, true);
            app.ImportDatabaseButton.Text = '3. Import Database';
            app.BeadTable = uitable(app.FileImportUIFigure);
            app.BeadTable.ColumnName = {'Selection'; 'Panel ID'; 'Bead Number'; 'Isotype'; 'Antibody'};
            app.BeadTable.ColumnRearrangeable = 'on';
            app.BeadTable.RowName = {};
            % Show the figure after all components are created
            app.FileImportUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Import_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FileImportUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FileImportUIFigure)
        end
    end
end